classdef RSFaultZ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RSFaultZUIFigure                matlab.ui.Figure
        PlotTab1                        matlab.ui.container.TabGroup
        MainTab_2                       matlab.ui.container.Tab
        PlotControlsPanel               matlab.ui.container.Panel
        ymaxEditField                   matlab.ui.control.NumericEditField
        ymaxEditFieldLabel              matlab.ui.control.Label
        yminEditField                   matlab.ui.control.NumericEditField
        yminLabel                       matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        PlotTypeDropDown                matlab.ui.control.DropDown
        PlotTypeDropDownLabel           matlab.ui.control.Label
        GeoAxes                         matlab.ui.control.UIAxes
        TheMoonTab                      matlab.ui.container.Tab
        TheMoonPanel                    matlab.ui.container.Panel
        PlotButton_Lunar                matlab.ui.control.Button
        AzimuthEditField                matlab.ui.control.NumericEditField
        AzimuthEditFieldLabel           matlab.ui.control.Label
        LatitudeEditField               matlab.ui.control.NumericEditField
        LatitudeEditFieldLabel          matlab.ui.control.Label
        LongitudeEditField              matlab.ui.control.NumericEditField
        LongitudeEditFieldLabel         matlab.ui.control.Label
        MoonAxes                        matlab.ui.control.UIAxes
        TabGroup2                       matlab.ui.container.TabGroup
        GridControlsTab                 matlab.ui.container.Tab
        LbdxiEditField                  matlab.ui.control.NumericEditField
        LbdxiEditFieldLabel             matlab.ui.control.Label
        dximEditField                   matlab.ui.control.NumericEditField
        dximEditFieldLabel              matlab.ui.control.Label
        GridPointsEditField             matlab.ui.control.NumericEditField
        GridPointsEditFieldLabel        matlab.ui.control.Label
        FourierSpaceCheckBox            matlab.ui.control.CheckBox
        ImportEditField                 matlab.ui.control.EditField
        ImportButton                    matlab.ui.control.Button
        LbkmLabel                       matlab.ui.control.Label
        hkmLabel                        matlab.ui.control.Label
        CreateGridButton                matlab.ui.control.Button
        InitialConditionsTab            matlab.ui.container.Tab
        SteadyStateConditionButtonGroup  matlab.ui.container.ButtonGroup
        ShearStressButton               matlab.ui.control.RadioButton
        StateVariableButton             matlab.ui.control.RadioButton
        LoadingRatemsEditField          matlab.ui.control.NumericEditField
        LoadingRatemsEditFieldLabel     matlab.ui.control.Label
        InitialVelocitymsEditField      matlab.ui.control.NumericEditField
        InitialVelocitymsEditFieldLabel  matlab.ui.control.Label
        RandomVariationEditField        matlab.ui.control.NumericEditField
        RandomVariationEditFieldLabel   matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        ElasticParametersPanel          matlab.ui.container.Panel
        vsmsEditField                   matlab.ui.control.NumericEditField
        SWaveSpeedmsEditFieldLabel      matlab.ui.control.Label
        PoissonRatioEditField           matlab.ui.control.NumericEditField
        PoissonRatioEditFieldLabel      matlab.ui.control.Label
        ShearModulusGPaEditField        matlab.ui.control.NumericEditField
        ShearModulusGPaEditFieldLabel   matlab.ui.control.Label
        EffectiveNormalStressButtonGroup  matlab.ui.container.ButtonGroup
        LambdaLabel                     matlab.ui.control.Label
        EffectiveStressLabel            matlab.ui.control.Label
        FluidDensityEditField           matlab.ui.control.NumericEditField
        FluidDensitykgm3EditFieldLabel  matlab.ui.control.Label
        SolidDensityEditField           matlab.ui.control.NumericEditField
        SolidDensitykgm3EditFieldLabel  matlab.ui.control.Label
        LithostaticStressButton         matlab.ui.control.RadioButton
        PorePressureRatioEditField      matlab.ui.control.NumericEditField
        NormalStressMPaEditField        matlab.ui.control.NumericEditField
        PorePressureRatioButton         matlab.ui.control.RadioButton
        ConstantMPaButton               matlab.ui.control.RadioButton
        EditField_5Label                matlab.ui.control.Label
        FluidFlowTab                    matlab.ui.container.Tab
        InitialPorePressureEditField    matlab.ui.control.NumericEditField
        InitialExcessPorePressureMPaEditFieldLabel  matlab.ui.control.Label
        DiffusivityLabel                matlab.ui.control.Label
        SourceFunctionCheckBox          matlab.ui.control.CheckBox
        SourceFunctionName              matlab.ui.control.EditField
        PorosityEditField               matlab.ui.control.NumericEditField
        PorosityEditFieldLabel          matlab.ui.control.Label
        FluidViscosityMPasEditField     matlab.ui.control.NumericEditField
        FluidViscosityMpasLabel         matlab.ui.control.Label
        Compressibility1MPaEditField    matlab.ui.control.NumericEditField
        Compressibility1MPaLabel        matlab.ui.control.Label
        Permeabilitym2EditField         matlab.ui.control.NumericEditField
        Permeabilitym2Label             matlab.ui.control.Label
        DiffusionCheckBox               matlab.ui.control.CheckBox
        Label                           matlab.ui.control.Label
        ExecutionTimesLabel             matlab.ui.control.Label
        TimeLabel                       matlab.ui.control.Label
        SimulationControlsPanel         matlab.ui.container.Panel
        datCheckBox                     matlab.ui.control.CheckBox
        RunTimeyrEditField              matlab.ui.control.NumericEditField
        RunTimeyrEditFieldLabel         matlab.ui.control.Label
        PlotsOnCheckBox                 matlab.ui.control.CheckBox
        StateLawDropDown                matlab.ui.control.DropDown
        StateLawDropDownLabel           matlab.ui.control.Label
        ResumeButton                    matlab.ui.control.Button
        StructureNameEditField          matlab.ui.control.EditField
        StructureNameLabel              matlab.ui.control.Label
        ExportButton                    matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        FrictionParametersPanel         matlab.ui.container.Panel
        mu0EditField                    matlab.ui.control.NumericEditField
        mu0EditFieldLabel               matlab.ui.control.Label
        v0EditField                     matlab.ui.control.NumericEditField
        v0msLabel                       matlab.ui.control.Label
        dcmEditField                    matlab.ui.control.NumericEditField
        dcmLabel                        matlab.ui.control.Label
        bEditField                      matlab.ui.control.NumericEditField
        bEditFieldLabel                 matlab.ui.control.Label
        aEditField                      matlab.ui.control.NumericEditField
        aEditFieldLabel                 matlab.ui.control.Label
        GeometryPanel                   matlab.ui.container.Panel
        GeometryDropDown                matlab.ui.control.DropDown
        GeometryDropDownLabel           matlab.ui.control.Label
        BurialDepthkmEditField          matlab.ui.control.NumericEditField
        BurialDepthkmEditFieldLabel     matlab.ui.control.Label
        FaultLengthkmEditField          matlab.ui.control.NumericEditField
        FaultLengthkmEditFieldLabel     matlab.ui.control.Label
        DipAngleEditField               matlab.ui.control.NumericEditField
        DipAngleEditFieldLabel          matlab.ui.control.Label
        SurfaceSlopeEditField           matlab.ui.control.NumericEditField
        SurfaceSlopeEditFieldLabel      matlab.ui.control.Label
        VelAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        p       % structure containing all properties and geometric information.
        STOP    % flag for stopping a simulation.
        Out     % stucture containing output from a completed simulation.
    end
    
    methods (Access = private)
        
        function DippingFaultSetUpUI(app)
        %%% Get parameter values from fields. This function is called by the CreateGridButton.
            
        %%% Run time of the simulation.
            RunTime = app.RunTimeyrEditField.Value;
            time = [0 RunTime];
            
        %%% Frictional parameters.
            a = app.aEditField.Value;
            b = app.bEditField.Value;
            d_c = app.dcmEditField.Value;                                       % [m]
            v_ref = app.v0EditField.Value;                                      % [m/s]
            mu_0 = app.mu0EditField.Value;           

        %%% Shear wave velocity.
            v_shear = app.vsmsEditField.Value;                                  % [m/s]

        %%% Compute the maximum effective normal stress, which is needed to determine the
        %%% numerical grid spacing.
        %%% UES_IC_Flag: "Update Effective Stress - Initial Conditions - Flag"
            UES_IC_Flag = false;
            EffStress_0 = UpdateEffectiveStress(app, UES_IC_Flag);              % [MPa]

        %%% Get the stress case and amend the strings to be more readable.
            selectedButton = app.EffectiveNormalStressButtonGroup.SelectedObject;
            if iscell(selectedButton.Text) == 1
                StressCase = selectedButton.Text{1};
            else
                StressCase = selectedButton.Text;
            end
            switch StressCase
                case 'Constant [MPa]'
                    StressCase = 'Constant';
                case 'Lithostatic Stress'
                    StressCase = 'Lithostatic';
                case 'Pore Pressure'
                    StressCase = 'Pore Pressure Ratio';
            end

%%%----------------------------------------------------------------------------------------------%%%
%%% Generate the stress change matrices according to the geometry.
%%%----------------------------------------------------------------------------------------------%%%

            switch(app.GeometryDropDown.Value)
                case 'Full Space'
                    Poisson_1 = app.PoissonRatioEditField.Value;
                    ShearMod_1 = 1e3*app.ShearModulusGPaEditField.Value;
                    ShearMod_2 = ShearMod_1;
                    Poisson_2 = Poisson_1;
                    
                    Beta = -(ShearMod_2*(1 - 2*Poisson_1) - ShearMod_1*(1 - 2*Poisson_2))...
                        ./(2*(ShearMod_2*(1 - Poisson_1) + ShearMod_1*(1 - Poisson_2)));

                    M = (2*ShearMod_1*ShearMod_2/(1 - Beta^2))/((ShearMod_2*(1 - Poisson_1)...
                        + ShearMod_1*(1 - Poisson_2)));
                    
                %%% Bimaterial parameters from Heimisson.
                    % M = ShearMod_1/(1 - Poisson_1);
                    % B = 0.6;
                    % Beta = 2*B*(1 + Poisson_1)/3;

                %%% radiation damping [MPa s / m].
                    eta = ShearMod_1/(2*v_shear);
                    
                %%% Length scales.    
                    H_s = pi*M*d_c/(EffStress_0*(b-a)); 
                    L_b = d_c*M/(b*EffStress_0);
                
                %%% Set up the numerical grid.
                    FaultLength = 1e3*app.FaultLengthkmEditField.Value;
                    dxi = app.dximEditField.Value;

                %%% Fourier space option.
                    if app.FourierSpaceCheckBox.Value
                        N_dxi = round(FaultLength/dxi);
                        n2 = nextpow2(N_dxi);
                        if 2^n2 < 0.9*N_dxi
                            N = 2^(n2 + 1);
                        else
                            N = 2^n2;
                        end
                        N_tot = 7*N;
                        dxi = FaultLength/(N - 1);
                        TotalLength = N_tot*dxi;                    
                        Xi = (0 : dxi : FaultLength)' - FaultLength/2;
                        N_pad = (N_tot - N)/2;
                        app.dximEditField.Value = dxi;
                    %%% Computate the wave number.
                        WaveNumber = fftshift(2*pi.*abs((-N_tot/2:N_tot/2-1)')./(TotalLength-dxi));
                        WaveNumber2 = 2*pi.*((-N_tot/2:N_tot/2-1)')./(TotalLength-dxi);
                                
                    else
                    %%% Piece-wise constant discretization.
                        N = app.GridPointsEditField.Value;
                        Xi = (dxi/2: dxi: N*dxi - dxi/2)' - FaultLength/2;

                    %%% Compute the elastic stress change matrices for the defined grid.                    
                        K_s = hodlr('handle', @(i,j) FullSpaceStress_HODLR(i, j, Xi', 1), N, N);
                        % K_s = full(K_s);
                        % K_s = hss('handle', @(i,j) FullSpaceStress_HODLR(i, j, Xi', 1), N, N);
                        % K_s = hodlr2hss(K_s);
                        % K_s = hodlr('handle', @(i,j) FullSpaceREPStress_HODLR(i, j, Xi', 1), N, N);
                        % K_n = hodlr('handle', @(i,j) FullSpaceStress_HODLR(i, j, Xi', 0), N, N);
                        K_n = 0;
                    end

%%%----------------------------------------------------------------------------------------------%%%
                case {'Thrust Fault', 'Normal Fault', 'Strike Slip'}
                    Poisson = app.PoissonRatioEditField.Value;
                    ShearMod = 1e3*app.ShearModulusGPaEditField.Value;
                    eta = ShearMod/(2*v_shear);             % radiation damping [MPa s / m]
                    
                %%% Length scales.    
                    H_s = pi*ShearMod*d_c/(EffStress_0*(1 - Poisson)*(b-a));
                    L_b = d_c*ShearMod/(b*EffStress_0*(1 - Poisson));
                    
                %%% Set up grid, xi is the along-fault coordinate.
                    FaultLength = 1e3*app.FaultLengthkmEditField.Value;
                    dxi = app.dximEditField.Value;
                    N = app.GridPointsEditField.Value;
                    Xi = 0*dxi + (dxi/2: dxi: N*dxi - dxi/2)';
    
                %%% Wedge geometry.
                    alpha = app.SurfaceSlopeEditField.Value;
                    beta = app.DipAngleEditField.Value;

                %%% Burial depth - depth of updip edge.
                    BurialDepth = 1e3*app.BurialDepthkmEditField.Value;

                %%% Zed is the fault's depth below the upper surface.
                    Zed = BurialDepth + Xi*(sin(pi*beta/180) + cos(pi*beta/180)*tan(pi*alpha/180));

                %%% Horizontal location.
                    X = Xi*cos(pi*beta/180);
                
                %%% Complex coordinates of the fault cell centers in the half-space.
                    Z = complex(X, BurialDepth + Xi*sin(pi*beta/180));

                %%% Complex coordinates of the fault cell edges in the half-space.
                    Xi_0 = [Xi - dxi/2; Xi(end) + dxi/2];
                    Z_0 = complex(Xi_0*cos(pi*beta/180), BurialDepth + Xi_0*sin(pi*beta/180));
                    
                %%% Compute the elastic stress change matrices for the defined grid depending on the
                %%% geometry.
                    switch(app.GeometryDropDown.Value)

                        case {'Thrust Fault', 'Normal Fault'}
                        %     K_s = hodlr('handle', @(i,j) WedgeStress_HODLR(i, j, Xi', beta, alpha,...
                        %         BurialDepth, 1), N, N);
                        %     K_n = hodlr('handle', @(i,j) WedgeStress_HODLR(i, j, Xi', beta, alpha,...
                        %         BurialDepth, 0), N, N);                                                       
                        % 
                        % %%% Reverse the sign of the normal stress change matrix for a normal fault.
                        %     if strcmp(app.GeometryDropDown.Value, 'Normal Fault')
                        %         K_n = -K_n;
                        %     end

                %%% 3.5.2026 - WedgeStress_HODLR_LS3.m has been tested and works with
                %%% BP3QD.
                            type = 'dipole';
                            sense = app.GeometryDropDown.Value;
                            K_s = hodlr('handle', @(i,j) WedgeStress_HODLR_LS3(i, j, Z, Z_0, alpha,...
                                1, type, sense), N, N);
                            K_n = hodlr('handle', @(i,j) WedgeStress_HODLR_LS3(i, j, Z, Z_0, alpha,...
                                0, type, sense), N, N);

                        case 'Strike Slip'
                            K_s = hodlr('handle', @(i,j) StrikeSlipStress_HODLR(i, j, Xi', beta), N, N);
                            K_n = 0;
                    end

%%%----------------------------------------------------------------------------------------------%%%
                case 'Lunar Thrust'
                %%% Mean radius of the Moon.
                    R = 1737.4e3;                                               % [m]
                    
                %%% Elastic parameters.
                    Poisson = app.PoissonRatioEditField.Value;
                    ShearMod = 1e3*app.ShearModulusGPaEditField.Value;          % [MPa]

                %%% Radiation damping.
                    eta = ShearMod/(2*v_shear);                              % [MPa s / m]
                    
                %%% Frictional length scales.
                    H_s = pi*ShearMod*d_c/(EffStress_0*(1 - Poisson)*(b-a));    % [m]
                    L_b = d_c*ShearMod/(b*EffStress_0*(1 - Poisson));           % [m]
                    
                %%% Set up grid, Xi is the along-fault coordinate of the fault cell
                %%% centers.
                    FaultLength = 1e3*app.FaultLengthkmEditField.Value;         % [m]
                    dxi = app.dximEditField.Value;                              % [m]
                    N = app.GridPointsEditField.Value;
                    Xi = 0*dxi + (dxi/2: dxi: N*dxi - dxi/2)';                  % [m]
    
                %%% Dip angle relative to the surface above the udip edge of the fault.
                    beta = app.DipAngleEditField.Value;                         % [deg]

                %%% Burial depth: depth of updip edge.
                    BurialDepth = 1e3*app.BurialDepthkmEditField.Value;         % [m]

                %%% This coordinate system places the origin at the center of the Moon,
                %%% and the updip edge of the fault at x = 0.
                %%% Y is the distance from y = 0.
                    Y = R - (BurialDepth + Xi*sin(pi*beta/180));                % [m]                               

                %%% Depth is the fault's depth below a horizontal surface extending from theta = pi/2.
                    % Depth = R - Y;                                            % [m]
                
                %%% Horizontal location measured from the updip edge of the fault.
                    X = Xi*cos(pi*beta/180);                                    % [m]
                
                %%% Complex coordinates of the fault cell centers.
                    Z = complex(X, Y);                                          % [m]

                %%% Depth of the fault cell ceneters below the Moon's surface.
                    Zed = R - abs(Z);
                
                %%% Complex coordinates of the fault cell edges.
                    Xi_0 = [Xi - dxi/2; Xi(end) + dxi/2];                       % [m]
                    Z_0 = complex(Xi_0*cos(pi*beta/180),...
                        R - (BurialDepth + Xi_0*sin(pi*beta/180)));             % [m]

                %%% Compute the elastic stress change matrices for the defined grid depending on the
                %%% geometry.
                    type = 'dipole';
                    sense = 'Thrust Fault';
                    K_s = hodlr('handle', @(i,j) DiscStress_HODLR(i, j, Z, Z_0, 1, type,...
                        sense, R), N, N);
                    K_n = hodlr('handle', @(i,j) DiscStress_HODLR(i, j, Z, Z_0, 0, type,...
                        sense, R), N, N);

%%%----------------------------------------------------------------------------------------------%%%
                case 'Landslide'
                    Poisson = app.PoissonRatioEditField.Value;
                    ShearMod = 1e3*app.ShearModulusGPaEditField.Value;
                    eta = ShearMod/(2*v_shear);             % radiation damping [MPa s / m]
                    
                %%% Length scales.
                    H_s = pi*ShearMod*d_c/(EffStress_0*(1 - Poisson)*(b-a));
                    L_b = d_c*ShearMod/(b*EffStress_0*(1 - Poisson));

                %%% Landslide geometry.                
                %%% FaultLength - length of the slide along slope.
                    FaultLength = 1e3*app.FaultLengthkmEditField.Value;
                    dxi = app.dximEditField.Value;
                    N = app.GridPointsEditField.Value;
 
                %%% Arclength along the slide basal surface. The up-slope edge of the
                %%% slide is at z = -L/2, and down-slope edge is at z = L/2 so that x 
                %%% increases as one moves down-slope along the slide surface.

                % Xi is not used for landslide
                %%% calculations. Instead, the complex coordinates Z of the basal surface
                %%% are used.
                %%% NOTE: if computing Xi this way is correct, then can just shift it to
                %%% form the centered arclength S.
                    Xi = 0*dxi + (dxi/2: dxi: N*dxi - dxi/2)';

                %%% Burial depth - thickness of a slide at its center.
                    BurialDepth = 1e3*app.BurialDepthkmEditField.Value;
                   
                %%% Height of the circle center.
                    H_c = (1/(2*BurialDepth))*((FaultLength/2)^2 - BurialDepth^2);
                    
                %%% Radius of curvature.
                    R = BurialDepth + H_c;
                    
                %%% Arc length goes in the direction of the polar angle. Arclength along the
                %%% slide basal surface at the cell centers.
                    s_1 = R*acos(FaultLength/(2*R));
                    s_2 = R*acos(-FaultLength/(2*R));
                    ArcLength = s_2 - s_1;
                    % S = (s_1: dxi: (N-1)*dxi+s_1)';
                    S = linspace(s_1, s_2, N + 1)';
                    % S = Xi + s_1;

                %%% Grid spacing. ds needs to match dxi determined from UpdateGridInfo.
                    % ds = (s_2 - s_1)/(N - 1);
                    
                %%% Along slide length goes in the opposite direction and starts at zero. 
                    % Xi = S(1:N-1) - s_1 + ds/2;

                %%% Edges of the the fault segments (sources).
                %%% Complex coordinates as a fuction of arc length.
                    Z_arclength = R*cos(S/R) + 1i*(R*sin(S/R) - H_c);

                %%% Complex coordinates as a fuction of along-slope coordintes.
                    % Z = flip(Z_arclength);
                    Z_0 = flip(Z_arclength);

                %%% Centers of the fault segments (recievers).
                    dx = diff(real(Z_0));
                    dy = diff(imag(Z_0));
                    Z = Z_0(1:N) + 0.5*(dx + 1i*dy);
                    
                %%% Compute the elastic stress change matrices for the defined grid depending on the
                %%% geometry. Here, d is the depth of the updip edge of the slide. It is set to zero
                %%% here, internally.
                    % d = 0;
                
                %%% Landslides have the same sense of slip as normal faults.
                    sense = 'Normal Fault';

                %%% Set alpha equal to zero, because the stress change functions are
                %%% computed using the dip angle relative to the free surface, not
                %%% relative to horizontal.
                    alpha = 0;

                %%% Shear stress.
                    type = 'dipole';
                    % K_s = hodlr('handle', @(i,j) WedgeStress_HODLR_LS2(i, j, Z, Z_0, alpha,...
                    %     1, type, sense), N, N);
                    K_s = hodlr('handle', @(i,j) WedgeStress_HODLR_LS3(i, j, Z, Z_0, alpha,...
                        1, type, sense), N, N);
                    
                %%% Normal stress.
                    % K_n = hodlr('handle', @(i,j) WedgeStress_HODLR_LS2(i, j, Z, Z_0, alpha,...
                    %     0, type, sense), N, N);
                    K_n = hodlr('handle', @(i,j) WedgeStress_HODLR_LS3(i, j, Z, Z_0, alpha,...
                        0, type, sense), N, N);

                %%% The coordinates were not rotated by alpha to compute the elastic stress
                %%% change functions, because they are the usual functions for a half space.
                %%% However, the coordinates need to be rotated to compute the local dip
                %%% angle, depth, and distance along the slide's basal surface.
                %%% alpha - surface slope.
                    alpha = app.SurfaceSlopeEditField.Value;
                    Rot = exp(1i*(pi/180)*alpha);
                    % Z_0_rot = Rot*Z_0;
                    Z_rot = Rot*Z;

                %%% Distance and vertical depth.
                    % X = real(Z_0_rot);
                    X = real(Z_rot);
                    Zed = -(tand(alpha)*X - (R^2 - (H_c*sind(alpha) - X).^2).^(1/2) + H_c*cosd(alpha));

                %%% Dip angle.
                    dydx = (H_c*sind(alpha) - X)./((R^2 - (H_c*sind(alpha) - X).^2).^(1/2));
                    beta = (180/pi)*atan(dydx);

            end
%%%----------------------------------------------------------------------------------------------%%%

        %%% External loading rate.
            % spy = 365.25*24*3600;
            % Loading = 1e-1/spy;
            Loading = 0;
            % Loading = real((v_plate/pi)*(pi/2 + asin((FaultLength/2 ...
            %     - (Xi - Xi(1)))/(FaultLength/2)))) + 1e-6*v_plate;

        %%% Create variable for the initial conditions vector.
            Vars_0 = [];


%%%----------------------------------------------------------------------------------------------%%%
%%% Create a parameters structure containing all necessary information to run a simulation.
%%%----------------------------------------------------------------------------------------------%%%
            app.p = struct('Geometry', [], 'Material', [], 'Friction', [], 'InitialConditions', [],...
                'Options', []);

        %%% Friction parameters.
            app.p.Friction = struct('a', a, 'b', b, 'd_c', d_c, 'v_ref', v_ref, 'mu_ref', mu_0,...
                'H_star', H_s, 'L_b', L_b);

        %%% Initial conditions.
            v_plate = app.LoadingRatemsEditField.Value;
            v_init = app.InitialVelocitymsEditField.Value;
            v_var = app.RandomVariationEditField.Value;
            SteadyStateOption = app.SteadyStateConditionButtonGroup.SelectedObject.Text;
            app.p.InitialConditions = struct('PlateRate', v_plate, 'InitialVelocity', v_init,...
            'Variation', v_var, 'SteadyStateCondition', SteadyStateOption, 'Vars_init', Vars_0);

        %%% Options.
            plots = true;

            app.p.Options = struct('StateLaw', app.StateLawDropDown.Value, 'Geometry',...
                app.GeometryDropDown.Value, 'EffectiveStress', StressCase, 'RunTime', time,...
                'Restart', 0, 'Plots', plots, 'Import', 0, 'FourierSpace', 0, 'FluidDiffusion', 0,...
                'FluidSource', 0, 'DataManagement', 'ode');

            % if app.datCheckBox.Value == 1
            %     app.p.Options.DataManagement = 'dat';
            % else
            %     app.p.Options.DataManagement = 'ode';
            % end

%%%----------------------------------------------------------------------------------------------%%%
        %%% Different parameters for the different geometries.
            switch(app.GeometryDropDown.Value)

                case 'Full Space'
                %%% Geometrical and numerical parameters.
                    if app.FourierSpaceCheckBox.Value

                        app.p.Geometry = struct('FaultLength', FaultLength, 'FaultCoordinates', Xi,...
                            'GridPoints', N, 'GridSpacing', dxi, 'Padding', N_pad, 'WaveNumber',...
                            [WaveNumber, WaveNumber2]);
                    else
                        app.p.Geometry = struct('FaultLength', FaultLength, 'FaultCoordinates', Xi,...
                            'GridPoints', N, 'GridSpacing', dxi, 'ShearKernel',  K_s,...
                            'NormalKernel', K_n);
                    end

                %%% Material parameters.
                    app.p.Material = struct('ShearModulus', ShearMod_1,  'PoissonRatio', Poisson_1,...
                        'BackgroundStress', EffStress_0, 'ShearWaveSpeed', v_shear,...
                        'RadiationDamping', eta);

                %%% Fourier space option.
                    app.p.Options.FourierSpace = app.FourierSpaceCheckBox.Value;

%%%----------------------------------------------------------------------------------------------%%%
                case {'Thrust Fault', 'Normal Fault', 'Strike Slip'}
                %%% Geometrical and numerical parameters.
                    app.p.Geometry = struct('SurfaceSlope', alpha, 'FaultDip', beta, 'BurialDepth',...
                        BurialDepth, 'FaultLength', FaultLength, 'FaultCoordinates', Xi, 'GridPoints',...
                        N, 'GridSpacing', dxi, 'ShearKernel', K_s, 'NormalKernel', K_n, 'Depth',...
                        Zed, 'Distance', X);

                %%% Material parameters.
                    app.p.Material = struct('ShearModulus', ShearMod, 'PoissonRatio', Poisson,...
                        'BackgroundStress', EffStress_0, 'ShearWaveSpeed', v_shear,...
                        'RadiationDamping', eta);

%%%----------------------------------------------------------------------------------------------%%%
                case 'Lunar Thrust'
                %%% Geometrical and numerical parameters.                    
                    app.p.Geometry = struct('FaultDip', beta, 'BurialDepth',...
                        BurialDepth, 'FaultLength', FaultLength, 'FaultCoordinates', Xi, 'GridPoints',...
                        N, 'GridSpacing', dxi, 'ShearKernel', K_s, 'NormalKernel', K_n, 'Depth',...
                        Zed, 'Distance', X);

                %%% Material parameters.                    
                    app.p.Material = struct('ShearModulus', ShearMod, 'PoissonRatio', Poisson,...
                        'BackgroundStress', EffStress_0, 'ShearWaveSpeed', v_shear,...
                        'RadiationDamping', eta);

%%%----------------------------------------------------------------------------------------------%%%
                case 'Landslide'
                %%% Geometrical and numerical parameters.
                    app.p.Geometry = struct('SurfaceSlope', alpha, 'FaultDip', beta, 'BurialDepth',...
                        BurialDepth, 'FaultLength', FaultLength, 'ArcLength', ArcLength, 'FaultCoordinates',...
                        Xi, 'GridPoints', N, 'GridSpacing', dxi, 'ShearKernel', K_s, 'NormalKernel', K_n,...
                        'Depth', Zed, 'Distance', X);

                %%% Material parameters.
                    app.p.Material = struct('ShearModulus', ShearMod, 'PoissonRatio', Poisson,...
                        'BackgroundStress', EffStress_0, 'ShearWaveSpeed', v_shear,...
                        'RadiationDamping', eta);
            end

%%%----------------------------------------------------------------------------------------------%%%
            %%% Add densities for specific cases.
            switch StressCase
                case 'Lithostatic'
                    app.p.Material.SolidDensity = app.SolidDensityEditField.Value;

                case 'Pore Pressure Ratio'
                    app.p.Material.SolidDensity = app.SolidDensityEditField.Value;
                    app.p.Material.FluidDensity = app.FluidDensityEditField.Value;
                    app.p.Material.PorePressureRatio = app.PorePressureRatioEditField.Value;
            end

%%%----------------------------------------------------------------------------------------------%%%
        %%% Add fluid flow properties
            if app.DiffusionCheckBox.Value == 1
            %%% Fluid flow parameter values.
                phi_0 = app.PorosityEditField.Value;
                Viscosity = app.FluidViscosityMPasEditField.Value;      % [MPa s]
                Compress = app.Compressibility1MPaEditField.Value;      % [1 / MPa]
                Perm = app.Permeabilitym2EditField.Value;               % [m^2]
                D_hydro = Perm/(phi_0*Compress*Viscosity);              % [m^2 / s]

                app.p.FluidFlow = struct('PoreFluidCompress', Compress, 'Permeability', Perm,...
                    'FluidViscosity', Viscosity, 'Porosity_ref', phi_0, 'HydraulicDiffusivity', D_hydro);
                app.p.Options.FluidDiffusion = true;
            else
                app.p.Options.FluidDiffusion = false;
            end

            if app.SourceFunctionCheckBox.Value == 1
            %%% The source function needs to be in the MATLAB search path. FuncName is the name of 
            %%% the source function m-file. E.G. if the m-file is called SourceFunction.m, then 
            %%% FuncName = 'SourceFunction'.                
                FuncName = app.SourceFunctionName.Value;
                
            %%% In order for the call to 'eval' to work, the source function name must include the 
            %%% function call signature. E.G. SourceFunction(Xi).
                FuncName = [FuncName, '(Xi)'];
                SourceFunction = eval(FuncName);
                app.p.FluidFlow.SourceFunction = SourceFunction;
                app.p.Options.FluidSource = true;
            else
                app.p.Options.FluidSource = false;
            end
            
        end % DippingFaultSetUpUI 'SourceFunction', SourceFunction);

%%%----------------------------------------------------------------------------------------------%%%
%%%----------------------------------------------------------------------------------------------%%%
        
        function GetParameters(app)
        %%% This function gets parameter values from numeric edit fields. It is called by the Start 
        %%% button. The start button should be deactivated if a grid doesn't exist, in which case p
        %%% will not exist as well. Therefore, this fuction just updates parameters/values in p that 
        %%% don't require a new grid.

        %%% This function also uses the grid parameters to generate the initial conditions
        %%% vector.
            
        %%% Run time of the simulation.            
            RunTime = app.RunTimeyrEditField.Value;
            time = [0 RunTime];

        %%% Update values in the parameters structure that do not require a new grid.
        %%% First get the parameters from the GUI fields, then write them into the
        %%% parameters structure.
            if app.p.Options.Import == 0

                a = app.aEditField.Value;
                v_ref = app.v0EditField.Value;
                mu_0 = app.mu0EditField.Value;

                b = app.bEditField.Value;
                d_c = app.dcmEditField.Value;
                EffStress_0 = app.NormalStressMPaEditField.Value;            
                ShearMod = 1e3*app.ShearModulusGPaEditField.Value;
                Poisson = app.PoissonRatioEditField.Value;
                v_shear = app.vsmsEditField.Value;

                H_s = pi*ShearMod*d_c/(EffStress_0*(1 - Poisson)*(b-a));
                eta = ShearMod/(2*v_shear);

                app.p.Friction.a = a;
                app.p.Friction.mu_ref = mu_0;
                app.p.Friction.v_ref = v_ref;
                app.p.Friction.H_star = H_s;
                app.p.Material.ShearWaveSpeed = v_shear;
                app.p.Material.RadiationDamping = eta;

                if app.DiffusionCheckBox.Value == 1
                %%% Fluid flow parameters.
                    phi_0 = app.PorosityEditField.Value;
                    Viscosity = app.FluidViscosityMPasEditField.Value;      % [MPa s]
                    Compress = app.Compressibility1MPaEditField.Value;      % [1 / MPa]
                    Perm = app.Permeabilitym2EditField.Value;               % [m^2]
                    D_hydro = Perm/(phi_0*Compress*Viscosity);              % [m^2 / s]

                    app.p.FluidFlow.PoreFluidCompress = Compress;
                    app.p.FluidFlow.Permeability = Perm;
                    app.p.FluidFlow.FluidViscosity = Viscosity;
                    app.p.FluidFlow.Porosity_ref = phi_0;
                    app.p.FluidFlow.HydraulicDiffusivity = D_hydro;
                end

                if app.SourceFunctionCheckBox.Value == 1                                  
                %%% In order for the call to 'eval' to work, the source function name must include the 
                %%% function call signature. E.G. SourceFunction(Xi).
                    FuncName = app.SourceFunctionName.Value;
                    FuncName = [FuncName, '(Xi)'];
                %%% Xi has to exist for the call to 'eval(FuncName)' to work.
                    Xi = app.p.Geometry.FaultCoordinates;
                    SourceFunction = eval(FuncName);
                    app.p.FluidFlow.SourceFunction = SourceFunction;
                end


            %%% Create and store the initial conditions. If the Import flag is true, then the initial 
            %%% conditions must already be set.
                Vars_init = InitialConditions(app);
                app.p.InitialConditions.Vars_init = Vars_init;

            %%% 5.19.2025 - SHOULD THIS BE OUTSIDE OF THE IMPORT STATEMENT?
                v_init = app.InitialVelocitymsEditField.Value;
                app.p.InitialConditions.InitialVelocity = v_init;

            end

        %%% Plate rate for plate boundary fault simulations.
            v_plate = app.LoadingRatemsEditField.Value;            

        %%% Simulation parameters.
            app.p.Options.RunTime = time;
            app.p.InitialConditions.PlateRate = v_plate;
            
        %%% Options.
            app.p.Options.Geometry = app.GeometryDropDown.Value;
            app.p.Options.StateLaw = app.StateLawDropDown.Value;
            % if app.datCheckBox.Value == 1
            %     app.p.Options.DataManagement = 'dat';
            % else
            %     app.p.Options.DataManagement = 'ode';
            % end
            
        end % GetParameters


        function Out = RunFaultSimUI(app)
        %%% This function sets up and runs the ODE solver.

        %%% Geometrical and numerical parameters.
            N = app.p.Geometry.GridPoints;
            spy = 24*365.25*3600;
            time = spy*app.p.Options.RunTime;
            Loading = 0;
            
        %%% Material parameters.
            v_plate = app.p.InitialConditions.PlateRate;
            eta = app.p.Material.RadiationDamping;                     
            
        %%% Friction parameters.
            a = app.p.Friction.a;
            b = app.p.Friction.b;
            d_c = app.p.Friction.d_c;
            mu_ref = app.p.Friction.mu_ref;
            v_ref = app.p.Friction.v_ref;

        %%% Initial conditions.
            if app.p.Options.Restart == 0
                Vars_0 = app.p.InitialConditions.Vars_init;
            else
                Vars_0 = app.Out.Final;            
            end

        %%% Options.
            StateCase = app.p.Options.StateLaw;
            GeoCase = app.p.Options.Geometry;
            PlotCase = app.PlotTypeDropDown.Value;

         %%% Landslide parameters.
            if strcmp(GeoCase, 'Landslide')
                grav = 9.81;
                beta = app.p.Geometry.FaultDip;
                Depth = app.p.Geometry.Depth;
                Density_s = app.SolidDensityEditField.Value;
                Mass = Density_s*Depth;

            %%% Shear stresses.
                tau_grav = grav*Mass.*sind(beta);       % [Pa]

            %%% Normal stresses.
                sigma_grav = grav*Mass.*cosd(beta);     % [Pa]
                % Density_w = 1000;
                % sigma_grav = grav*Mass.*cosd(beta) - grav*Density_w*Depth;

            %%% Pore pressure source function.
            %%% In order for the call to 'eval' to work, the source function name must include the 
            %%% function call signature. E.G. SourceFunction(Xi).
                % FuncName = app.SourceFunctionName.Value;
                % FuncName = [FuncName, '(Xi)'];
                FuncName = 'LACCE_SourceFunction(Xi)';
            %%% Xi has to exist for the call to 'eval(FuncName)' to work.
                Xi = app.p.Geometry.FaultCoordinates;
                SourceFunction = eval(FuncName);
                % app.p.FluidFlow.SourceFunction = SourceFunction;
            end

%%%----------------------------------------------------------------------------------------------%%%            
        %%% Run the simulation according to the options.

        %%% 1. Get the distance and depth vectors, which are needed for the plotting function.            
            switch(GeoCase)
                case 'Full Space'
                    X = app.p.Geometry.FaultCoordinates;
                    Z = nan(size(X));

                case {'Thrust Fault', 'Normal Fault', 'Strike Slip', 'Lunar Thrust', 'Landslide'}
                    X = app.p.Geometry.Distance;
                    Z = app.p.Geometry.Depth;                           
            end

%%%----------------------------------------------------------------------------------------------%%%
        %%% 2. Set the ode solver options according to how the simulation output data will be managed.
            switch(app.p.Options.DataManagement)
                case 'ode'
                    if strcmp(GeoCase, 'Landslide')
                        spd = 24*3600;
                        options = odeset('OutputFcn', @PlotVel_ode, 'Events', @Stop, 'Refine', 1,...
                            'Vectorized', 'on', 'JPattern', Landslide_JPattern(N),...
                            'RelTol', 1e-4, 'AbsTol', 1e-6, 'MaxStep', spd);
    
                        % options = odeset('OutputFcn', @PlotVel_ode, 'Events', @Stop, 'Refine', 1,...
                        %     'Jacobian', @Landslide_Jac, 'RelTol', 1e-4, 'AbsTol', 1e-6);
                        
                        % options = odeset('OutputFcn', @PlotVel_ode, 'Events', @Stop);
                    else
                        options = odeset('OutputFcn', @PlotVel_ode, 'Events', @Stop, 'Refine', 1,...
                            'RelTol', 1e-4, 'AbsTol', 1e-6);
                    end

                case 'dat'
                    options = odeset('OutputFcn', @PlotVel_dat, 'Events', @Stop, 'Refine', 1,...
                        'RelTol', 1e-4, 'AbsTol', 1e-6);
            end

%%%----------------------------------------------------------------------------------------------%%%
        %%% 3. Set up fluid flow options.
            if app.p.Options.FluidDiffusion || app.p.Options.FluidSource
        %%% Fluid flow is on. Either a source term function has been provided, and/or along
        %%% fault diffusion is enabled.

                if app.p.Options.FluidDiffusion
        %%% Hydraulic diffusivity.
                    D_hydro = app.p.FluidFlow.HydraulicDiffusivity;
        %%% Combined central difference operator. NEED TO ADD BOUNDARY CONDITIONS.
                    D2 = three_point_centered_uni_D2(X(1,1), X(end,1), N);
        %%% No-flow boundary conditions.
                    % [xzz_z0,xzz_zL] = four_point_neumann_bcs_D2(X(1,1) , X(end,1) ,x,xz_z0,xz_zL, 1, 1);
                    D2 = D_hydro*D2;
        %%% Convert D2 into a HODLR matrix. This is actually about an order of magnitude
        %%% slower than using the sparse form from three_point_centered_uni_D2.m. But the 
        %%% two methods have the same scaling with element number.
                    % D2 = hodlr('banded', D2);
                else
                    D2 = 0;
                end

                if app.p.Options.FluidSource
        %%% Pressure source term.
                    SourceFunction = app.p.FluidFlow.SourceFunction;                
                else
        %%% There is no source term. This anonymous functions outputs zero for any input.
                    SourceFunction = @(t) 0;
                end

        %%% If resuming with a time-dependent source function, the time when the StopButton 
        %%% was pushed is needed to resume with the correct source term.
                if app.p.Options.Restart == 1
                    t_resume = app.Out.Time(end);
                else
                    t_resume = 0;
                end
            end

%%%----------------------------------------------------------------------------------------------%%%
        %%% 4a. Enable the PlotControlsPanel when the simulation ends.
            app.PlotControlsPanel.Enable = "off";

        %%% 4b. Run simulation with full-space geometry and Fourier transforms.
            ShearMod = app.p.Material.ShearModulus;
            Poisson = app.p.Material.PoissonRatio;

            if app.p.Options.FourierSpace            
                WaveNumber = app.p.Geometry.WaveNumber(:,1);
                WaveNumber2 = app.p.Geometry.WaveNumber(:,2);
                N_pad = app.p.Geometry.Padding;
                M = ShearMod/(1 - Poisson);
                Beta = 0;
                [Time, Vars] = ode89(@FullSpaceFault, time, Vars_0, options, X, Z, N);

        %%% 4c. Run simulation for any geometry using H-matrices.
            else                                 
                K_s = (ShearMod/(2*pi*(1 - Poisson)))*app.p.Geometry.ShearKernel;
                K_n = (ShearMod/(2*pi*(1 - Poisson)))*app.p.Geometry.NormalKernel;
                
        %%% Run the simulation according to the data management option and pore pressure 
        %%% options.
                switch(app.p.Options.DataManagement)
                    case 'ode'
                        if app.p.Options.FluidDiffusion || app.p.Options.FluidSource
                            [Time, Vars] = ode89(@PressureFault, time, Vars_0, options, X, Z, N);
                        else
                            if strcmp(GeoCase, 'Landslide')
                                [Time, Vars] = ode15s(@Landslide, time, Vars_0, options, X, Z, N);
                            else
                                [Time, Vars] = ode89(@DippingFault, time, Vars_0, options, X, Z, N);                                
                            end
                        end

                    case 'dat'
                        if app.p.Options.FluidDiffusion || app.p.Options.FluidSource
                            ode89(@PressureFault, time, Vars_0, options, X, Z, N);
                        else
                            ode89(@DippingFault, time, Vars_0, options, X, Z, N);
                        end
                end

            end

        %%% 4d. Enable the PlotControlsPanle when the simulation ends.
            app.PlotControlsPanel.Enable = "on";

%%%----------------------------------------------------------------------------------------------%%%
        %%% 5. Collect the simulation output.
        %%% DATA MANAGEMENT OPTION IS CURRENTLY ONLY ACTIVE FOR THRUST FAULTS.
            switch(app.p.Options.DataManagement)
                case 'ode'
                %%% Collect simulation output into a single structure, then clear Vars from
                %%% memory.
                    if app.p.Options.FluidDiffusion || app.p.Options.FluidSource
                        Out = struct('Time', Time, 'Vel', Vars(:,1:N), 'ShearStress',...
                            Vars(:, N+1:2*N), 'State', Vars(:, 2*N+1:3*N), 'NormalStress',...
                            Vars(:, 3*N+1:4*N), 'PorePressure', Vars(:, 4*N+1:5*N));

                %%% 3.11.2026 - elseif statement added for landslide testing, can be
                %%% removed later.
                    elseif strcmp(GeoCase, 'Landslide')
                        Out = struct('Time', Time, 'Vel', Vars(:,1:N), 'State',...
                            Vars(:, N+1:2*N), 'Slip', Vars(:, 2*N+1:3*N));

                    else
                        Out = struct('Time', Time, 'Vel', Vars(:,1:N), 'ShearStress',...
                            Vars(:, N+1:2*N), 'State', Vars(:, 2*N+1:3*N), 'NormalStress',...
                            Vars(:, 3*N+1:4*N));
                    end
                    clear Vars
                    
        %%% Calculate slip.
                    if ~strcmp(GeoCase, 'Landslide')
                        Slip = [zeros(1, N); cumsum(repmat(diff(Out.Time),1,N).*Out.Vel(2:end,:))];
                        Out.Slip = Slip;
                    end
                    
        %%% Final conditions.
        %%% 3.11.2026 - if statement addded for landslide testing, can be removed
        %%% later.
                    if strcmp(GeoCase, 'Landslide')
                        Out.Final = [Out.Vel(end,:)'; Out.State(end,:)';...
                            Out.Slip(end,:)'];
                    else
                        Out.Final = [Out.Vel(end,:)'; Out.ShearStress(end,:)'; Out.State(end,:)';...
                            Out.NormalStress(end,:)'];
                    end
                    if app.p.Options.FluidDiffusion || app.p.Options.FluidSource
                        Out.Final = [Out.Final; Out.PorePressure(end,:)'];
                    end

                case 'dat'
        %%% For dat data management, all of the simulation output is stored in .dat files in
        %%% the working directory. These files may be large, and attempting to load them
        %%% could cause MATLAB to crash. 
        %%% IT MAY BE POSSIBLE TO LOAD THE FINAL VARS FOR RESTARTING.
                    Out = [];
            end
            
%%%----------------------------------------------------------------------------------------------%%%
%%% Governing equaitions for a full-space fault using Fourier transforms.
            function dVarsdt = FullSpaceFault(t, Vars, X, Z, N)
                vel = Vars(1:N);
                % tau = Vars(N+1:2*N);
                state = Vars(2*N+1:3*N);
                sigma = Vars(3*N+1:4*N);
            
            %%% State evolution.
                switch(StateCase)
                    case 'Aging'
                        dstatedt = 1 - vel.*state./d_c;
                    case 'Slip'
                        dstatedt = -(vel.*state./d_c).*log(vel.*state./d_c);
                end
            %%% Shear stress
                Vel2 = [v_plate*ones(N_pad,1); vel; v_plate*ones(N_pad,1)];
                % Vel2 = [zeros(N_pad,1); vel - v_plate; zeros(N_pad,1)];
                Ve2_fft = fft(Vel2);
                Stress_fft = WaveNumber.*Ve2_fft;
                % Stress_fft(1) = -v_plate;
                ElasticStress = ifft(Stress_fft);
                dtaudt = -(M/2)*ElasticStress(N_pad + 1: N_pad + N);
            
            %%% Normal stress
                % NormFull_fft = 1i*WaveNumber2.*Ve2_fft;
                % dsigmadt = real(fftshift(ifft(ifftshift(NormFull_fft))));
                % dsigmadt = -(M*Beta/2)*dsigmadt(N_pad + 1 : N_pad + N);
                dsigmadt = -(M*Beta/2)*zeros(N,1);
            
            %%% Velocity equation.
                g = exp((mu_ref + b.*log(v_ref.*state./d_c))./a);
                u = (vel/(2*v_ref)).*g;
                f = asinh(u);
                dgdt = (b.*g./(a.*state)).*dstatedt;
                dvdt = (g + (2*v_ref*eta./(a.*sigma))...
                    .*(1 + u.^2).^(1/2)).^(-1).*((2*v_ref./(a.*sigma))...
                    .*(1 + u.^2).^(1/2).*(Loading + dtaudt - a.*f.*dsigmadt)...
                    - vel.*dgdt);
                
            %%% Combine evolution equations.
                dVarsdt = [dvdt; dtaudt; dstatedt; dsigmadt];
            end % FullSpaceFault


%%%----------------------------------------------------------------------------------------------%%%
%%% Governing equations using stress change kernels. NEED TO RENAME THIS FUNCTION, SINCE IT IS USED FOR 
%%% ANY GEOMETRY AND NOT JUST DIPPING FAULTS.
            function dVarsdt = DippingFault(t, Vars, X, Z, N)
                vel = Vars(1:N);
                % tau = Vars(N+1:2*N);
                state = Vars(2*N+1:3*N);
                sigma = Vars(3*N+1:4*N);
            
            %%% Shear stress equation.
                dtaudt = K_s*(vel - v_plate);

            %%% Normal stress equation.
                dsigmadt = K_n*(vel - v_plate);

            %%% State evolution.
                switch(StateCase)
                    case 'Aging'
                        dstatedt = 1 - vel.*state./d_c;
                    case 'Slip'
                        dstatedt = -(vel.*state./d_c).*log(vel.*state./d_c);
                end

            %%% Velocity equation.
                g = exp((mu_ref + b.*log(v_ref.*state./d_c))./a);
                u = (vel/(2*v_ref)).*g;
                f = asinh(u);
                dgdt = (b.*g./(a.*state)).*dstatedt;
                dvdt = (g + (2*v_ref*eta./(a.*sigma))...
                    .*(1 + u.^2).^(1/2)).^(-1).*((2*v_ref./(a.*sigma))...
                    .*(1 + u.^2).^(1/2).*(Loading + dtaudt - a.*f.*dsigmadt)...
                    - vel.*dgdt);
                
            %%% Combine evolution equations.
                dVarsdt = [dvdt; dtaudt; dstatedt; dsigmadt];
                
            end %DippingFault

%%%----------------------------------------------------------------------------------------------%%%
%%% Governing equations using stress change kernels for landslide. 
            function dVarsdt = Landslide(t, Vars, X, Z, N)
                % vel = Vars(1:N);
                % state = Vars(N+1:2*N);
                % slip = Vars(2*N+1:3*N);

                vel = Vars(1:N,:);
                state = Vars(N+1:2*N,:);
                slip = Vars(2*N+1:3*N,:);

                % vel = Vars(1:N);
                % % tau = Vars(N+1:2*N);
                % state = Vars(2*N+1:3*N);
                % % sigma = Vars(3*N+1:4*N);
                % slip = Vars(4*N+1:5*N);
            
            %%% Restrict backwards slip
                vel(vel < 0) = 0;

            %%% Slip equation.
                dslipdt = vel;

            %%% Shear stresses.
                tau_elas = 1e6*K_s*slip;                % [Pa]
                % dtaudt = K_s*vel;                       % [MPa/s]

            %%% Normal stresses.
                sigma_elas = 1e6*K_n*slip;              % [Pa]
                % dsigmadt = K_n*vel;                     % [MPa/s]

            %%% Pore fluid pressure.
                % t_start = 3*spy;
                t_start = 0;
                if t < t_start
                    p_f = 0;
                else
                    p_f = 1e6*SourceFunction(t - t_start).*(Z/max(Z));
                end

            %%% State evolution.
                switch(StateCase)
                    case 'Aging'
                        dstatedt = 1 - (vel + v_ref).*state./d_c;
                    case 'Slip'
                        dstatedt = -((vel + v_ref).*state./d_c).*log((vel + v_ref).*state./d_c);
                end                

            %%% Friction equation.
                mu = mu_ref + a*log((vel + v_ref)/v_ref) + b*log(v_ref*state/d_c);

            %%% Velocity equation.
                % dvdt = (1./Mass).*K_s*slip + 9.81*sind(beta) - mu.*((1./Mass).*K_n*slip...
                %     - 9.81*cosd(beta));
                dvdt = (1./Mass).*(tau_grav + tau_elas - mu.*(sigma_grav + sigma_elas - p_f));
                % dvdt = (1./Mass).*(tau_grav - mu.*sigma_grav);

            %%% Restrict backwards slip, again?
                dvdt(vel == 0 & dvdt < 0) = 0;

            %%% Friction equation.
                % dmudt = a*dvdt./(vel + v_ref) + b*dstatedt./state;
                
            %%% Combine evolution equations.
                dVarsdt = [dvdt; dstatedt; dslipdt];
                % dVarsdt = [dvdt; dstatedt; dslipdt; dmudt];
                % dVarsdt = [dvdt; dtaudt; dstatedt; dsigmadt; dslipdt];
                
            end % Landslide

            function S = Landslide_JPattern(N)
            %%% Provide the sparsity pattern of the Jacobian for the lanslide ode
            %%% system.


            %%%-------------------------------------------------------------------%%%
            %%% Velocity equation.

            %%% d(dvdt)/dv.
                df_vel_dv = speye(N);

            %%% d(dvdt)/dstate.                
                df_vel_dstate = speye(N);

            %%% d(dvdt)/dslip.                
                df_vel_dslip = sparse(ones(N));

            %%%-------------------------------------------------------------------%%%
            %%% State equation.

            %%% d(dstatedt)/dv.
                df_state_dv = speye(N);

            %%% d(dstatedt)/dstate.
                df_state_dstate = speye(N);

            %%% d(dstatedt)/dslip.
                df_state_dslip = sparse(N,N);

            %%%-------------------------------------------------------------------%%%
            %%% Slip equation.

            %%% d(dslipdt)/dv.
                df_slip_dv = speye(N);

            %%% d(dslipdt)/dstate.
                df_slip_dstate = sparse(N,N);

            %%% d(dslipdt)/dstate.
                df_slip_dslip = sparse(N,N);

            %%%-------------------------------------------------------------------%%%
            %%% Form the Jacobian matrix.
                S = [df_vel_dv   df_vel_dstate   df_vel_dslip;...
                     df_state_dv df_state_dstate df_state_dslip;...
                     df_slip_dv  df_slip_dstate  df_slip_dslip];
            end

            function dfdy = Landslide_Jac(t, Vars, X, Z, N)
            %%% Provide the ode Jacobian for landslide simulations. The landslide ode
            %%% system is stiff, so need to use one of the stiff solvers (e.g. ode15s). 
            %%% Providing the Jacobian matrix increases numerical efficiency. This 
            %%% Jacobian is formulated for use with the aging law.

            %%% There are nine matrix blocks. Each block is an NxN matrix:
           
            %%% | d(dvdt)/dv      d(dvdt)/dstate      d(dvdt)dslip      |
            %%% | d(dstatedt)/dv  d(dstatedt)/dstate  d(dstatedt)/dslip |
            %%% | d(dslipdt)dv    d(dslipdt)/dstate   d(dslipdt)/dslip  |
            
            %%% Variables.
                vel = Vars(1:N);
                state = Vars(N+1:2*N);
                slip = Vars(2*N+1:3*N);

            %%% Restrict backwards slip
                vel(vel < 0) = 0;

            %%% Shear stresses.
                tau_elas = 1e6*K_s*slip;                % [Pa]

            %%% Normal stresses.
                sigma_elas = 1e6*K_n*slip;              % [Pa]
                
            %%% Friction equation.
                mu = mu_ref + a*log((vel + v_ref)/v_ref) + b*log(v_ref*state/d_c);
           
            %%%-------------------------------------------------------------------%%%
            %%% Velocity equation.
                dvdt = (1./Mass).*(tau_grav + tau_elas - mu.*(sigma_grav + sigma_elas));
                i_vel = (vel == 0 & dvdt < 0);

            %%% d(dvdt)/dv.
                df_vel_dv_diag = (a./(v_ref + vel)).*(sigma_grav + sigma_elas);
                df_vel_dv_diag(i_vel) = 0;
                df_vel_dv = diag(df_vel_dv_diag);

            %%% d(dvdt)/dstate.
                df_vel_dstate_diag = (b./state).*(sigma_grav + sigma_elas);
                df_vel_dstate_diag(i_vel) = 0;
                df_vel_dstate = diag(df_vel_dstate_diag);

            %%% d(dvdt)/dslip.
                df_vel_dslip = full(K_s).*repmat((slip./Mass)', N, 1)...
                    + full(K_s).*repmat((mu.*slip./Mass)', N, 1);
                df_vel_dslip(i_vel,:) = 0;

            %%%-------------------------------------------------------------------%%%
            %%% State equation.

            %%% d(dstatedt)/dv.
                df_state_dv = diag(-state/d_c);

            %%% d(dstatedt)/dstate.
                df_state_dstate = diag(-(v_ref + vel)/d_c);

            %%% d(dstatedt)/dslip.
                df_state_dslip = zeros(N);

            %%%-------------------------------------------------------------------%%%
            %%% Slip equation.

            %%% d(dslipdt)/dv.
                df_slip_dv = eye(N);

            %%% d(dslipdt)/dstate.
                df_slip_dstate = zeros(N);

            %%% d(dslipdt)/dstate.
                df_slip_dslip = zeros(N);

            %%%-------------------------------------------------------------------%%%
            %%% Form the Jacobian matrix.
                dfdy = [df_vel_dv   df_vel_dstate   df_vel_dslip;...
                        df_state_dv df_state_dstate df_state_dslip;...
                        df_slip_dv  df_slip_dstate   df_slip_dslip];
            end


%%%----------------------------------------------------------------------------------------------%%%
%%% Governing equations using stress change kernels, with 1D fluid flow along the fault.
%%% No flow allowed perpendicular to the fault.
            function dVarsdt = PressureFault(t, Vars, X, Z, N)
                vel = Vars(1:N);
                % tau = Vars(N+1:2*N);
                state = Vars(2*N+1:3*N);
                sigma = Vars(3*N+1:4*N);
                pressure = Vars(4*N+1:5*N);
            
            %%% Shear stress equation.
                dtaudt = K_s*(vel - v_plate);

            %%% Normal stress equation.
                sigma = sigma - pressure;
                dsigmadt = K_n*(vel - v_plate);

            %%% Pore pressure equation.
                dpressuredt = D2*pressure + SourceFunction(t + t_resume);

            %%% State evolution.
                switch(StateCase)
                    case 'Aging'
                        dstatedt = 1 - vel.*state./d_c;
                    case 'Slip'
                        dstatedt = -(vel.*state./d_c).*log(vel.*state./d_c);
                end

            %%% Velocity equation.
                g = exp((mu_ref + b.*log(v_ref.*state./d_c))./a);
                u = (vel/(2*v_ref)).*g;
                f = asinh(u);
                dgdt = (b.*g./(a.*state)).*dstatedt;
                dvdt = (g + (2*v_ref*eta./(a.*sigma))...
                    .*(1 + u.^2).^(1/2)).^(-1).*((2*v_ref./(a.*sigma))...
                    .*(1 + u.^2).^(1/2).*(Loading + dtaudt - a.*f.*(dsigmadt - dpressuredt))...
                    - vel.*dgdt);
                
            %%% Combine evolution equations.
                dVarsdt = [dvdt; dtaudt; dstatedt; dsigmadt; dpressuredt];

            end % PressureFault            
            
%%%----------------------------------------------------------------------------------------------%%%
%%% ode events function. Stops the simulation when the "stop" button is pushed.
            function [position, isterminal, direction] = Stop(t, Vars, X, Z, N)                              
                position = app.STOP;
                isterminal = 1;
                direction = 0;                
            end %Stop

%%%----------------------------------------------------------------------------------------------%%%
%%% ode output function, plots live simulation data.
            function status = PlotVel_ode(time, Vars, flag, X, Z, N)
                  
                persistent v_line b_line %timerval
                status = 0;
                
                switch(flag)
                    case 'init'
                    %%% Initialize the plotting figures regardless of whether PlotsOnCheckBox is 
                    %%% checked, so that the plots can be turned on during a simulation that starts 
                    %%% with the plots turned off.
                        % if app.PlotsOnCheckBox.Value
                            
                            switch(PlotCase)

                                case 'Geometry'
                                    hold(app.GeoAxes, 'on')
                                    b_line = line(app.GeoAxes, X/1e3, nan(size(X)), 'Color', 'b',...
                                        'LineWidth', 2);
                                    hold(app.GeoAxes, 'off')

                                case 'Shear Stress'
                                    cla(app.GeoAxes)
                                    b_line = line(app.GeoAxes, X/1e3, Vars(N+1:2*N), 'Color', 'b',...
                                        'LineWidth', 2);

                                case 'Normal Stress'
                                    cla(app.GeoAxes)
                                    b_line = line(app.GeoAxes, X/1e3, Vars(3*N+1:4*N), 'Color', 'b',...
                                        'LineWidth', 2);

                                case 'Pore Pressure'
                                    cla(app.GeoAxes)
                                    b_line = line(app.GeoAxes, X/1e3, Vars(4*N+1:5*N), 'Color', 'b',...
                                        'LineWidth', 2);                               
                            end                            

                            v_line = semilogy(app.VelAxes, X/1e3, Vars(1:N), 'r', 'LineWidth', 2);
                            v_line.YLimInclude = 'off';
                            v_line.XLimInclude = 'off';
                        % end

                        % timerval = tic;

                    case ''
                    %%% Display the in-simulation time. Do this without drawnow?
                        r = mod(time(1), spy);
                        years = (time(1) - r)/spy;
                        days = r/(24*3600);
                        app.TimeLabel.Text = sprintf('Time: %8d years, %6.2f days', [years, days]);                        
                        app.Label.Text = sprintf(': %6.2f', toc);
                        
                    %%% This is some code to slow down the drawnow command even more than the limitrate 
                    %%% option does.
                        % time2 = toc(timerval);
                        % if time2 > 0.1
                        %     drawnow;
                        %     timerval = tic;
                        % end
                        drawnow limitrate
                        
                    %%% Plot the logarithm of the slip velocity.
                        if app.PlotsOnCheckBox.Value                            
                            set(v_line, 'XData', X/1e3, 'YData', Vars(1:N));

                            switch(PlotCase)

                            %%% Plot blue fast speeds on GeoAxes. Code has problems when there are multiple blue
                            %%% patches that aren't contiguous. Need other cases.
                                case 'Geometry'

                                    switch(GeoCase)

                                        case {'Thrust Fault', 'Normal Fault', 'Strike Slip'}
                                            i_blue = Vars(1:N) > 1e-2;
                                            set(b_line, 'XData', 1e-3*X(i_blue), 'YData', 1e-3*Z(i_blue))
                                            
                                    end
                                    % plot(app.GeoAxes, 1e-3*X(i_blue), zeros(sum(i_blue),1), 'b', 'LineWidth', 2)

                            %%% Plot the shear stress.
                                case 'Shear Stress'
                                    set(b_line, 'XData', 1e-3*X, 'YData', Vars(N+1:2*N));

                            %%% Plot the normal stress.
                                case 'Normal Stress'
                                    set(b_line, 'XData', 1e-3*X, 'YData', Vars(3*N+1:4*N));

                            %%% Plot the pore pressure.
                                case 'Pore Pressure'
                                    set(b_line, 'XData', 1e-3*X, 'YData', Vars(4*N+1:5*N));
                            end
                        end

                    case 'done'
                        hold(app.GeoAxes, 'off')

                end % switch
            end % PlotVel_ode

%%%----------------------------------------------------------------------------------------------%%%
%%% dat output function, plots live simulation data.
            function status = PlotVel_dat(time, Vars, flag, X, Z, N)
                  
                persistent gs
                status = 0;
                
                switch(flag)
                    case 'init'
                    %%% Initialize the plotting figures.
                        % if app.PlotsOnCheckBox.Value
                        %     hold(app.GeoAxes, 'on')
                        %     b_line = line(app.GeoAxes, X/1e3, nan(size(X)), 'Color', 'b',...
                        %         'LineWidth', 2);
                        %     hold(app.GeoAxes, 'off')
                        % 
                        %     v_line = semilogy(app.VelAxes, X/1e3, Vars(1:N), 'r', 'LineWidth', 2);
                        %     v_line.YLimInclude = 'off';
                        %     v_line.XLimInclude = 'off';
                        % end
                        
                    %%% Initialize the .dat files.
                        Vn = 'Vel.dat';
                        Tn = 'Time.dat';
                        Tan = 'Tau.dat';
                        Sig_n = 'Sigma.dat';
                        Stn = 'State.dat';
                        gs.Vel = SaveStreamData('Init',Vn);
                        gs.Time = SaveStreamData('Init',Tn);
                        gs.Tau = SaveStreamData('Init',Tan);
                        gs.Sigma = SaveStreamData('Init',Sig_n);
                        gs.State = SaveStreamData('Init', Stn);
                        
                    %%% Write to the .dat files for the initial conditions.
                        SaveStreamData('Write', gs.Vel, Vars(1:N, 1));
                        SaveStreamData('Write', gs.Time, time(1));
                        SaveStreamData('Write', gs.State, Vars(2*N+1 : 3*N, 1));
                        SaveStreamData('Write', gs.Tau, Vars(N+1 : 2*N, 1));
                        SaveStreamData('Write', gs.Sigma, Vars(3*N+1 : 4*N, 1));
                
                        
                    case ''
                    %%% Display the in-simulation time. Do this without drawnow?
                        r = mod(time(1), spy);
                        years = (time(1) - r)/spy;
                        days = r/(24*3600);
                        app.TimeLabel.Text = sprintf('Time: %8d years, %6.2f days', [years, days]);                        
                        app.Label.Text = sprintf(': %6.2f', toc);
                        
                    %%% This is some code to slow down the drawnow command even more than the limitrate 
                    %%% option does. For no plots, seeing 11% decrease. For plots on, seeing 25% decrease.
                        % time2 = toc(timerval);
                        % if time2 > 0.1
                        %     drawnow;
                        %     timerval = tic;
                        % end
                        drawnow limitrate

                    %%% Plot the logarithm of the slip velocity.
%                         if app.PlotsOnCheckBox.Value                            
%                             set(v_line, 'XData', X/1e3, 'YData', Vars(1:N));                            
% 
%                         %%% Plot blue fast speeds on GeoAxes. Code has problems when there are multiple blue
%                         %%% patches that aren't contiguous. Need other cases.
%                             switch(GeoCase)
%                                 case {'Thrust Fault', 'Normal Fault', 'Strike Slip'}
%                                     i_blue = Vars(1:N) > 1e-2;
%                                     set(b_line, 'XData', 1e-3*X(i_blue), 'YData', 1e-3*Z(i_blue))
%                             end
% %                             plot(app.GeoAxes, 1e-3*X(i_blue), zeros(sum(i_blue),1), 'b', 'LineWidth', 2)
%                         end

                    %%% Write to the .dat files during simulation         
                        SaveStreamData('Write', gs.Vel, Vars(1:N, 1));
                        SaveStreamData('Write', gs.Time, time(1));
                        SaveStreamData('Write', gs.State, Vars(2*N+1 : 3*N, 1));
                        SaveStreamData('Write', gs.Tau, Vars(N+1 : 2*N, 1));
                        SaveStreamData('Write', gs.Sigma, Vars(3*N+1 : 4*N, 1));
                        
                    case 'done'
                        % hold(app.GeoAxes, 'off')
                    %%% Finalize the .dat files.
                        SaveStreamData('Finalize', gs.Vel);
                        SaveStreamData('Finalize', gs.Time);
                        SaveStreamData('Finalize', gs.Tau);
                        SaveStreamData('Finalize', gs.Sigma);
                        SaveStreamData('Finalize', gs.State);                                               
                end % switch            
            end % PlotVel_dat
        end % RunFaultSimUI        
        
        function DisableControls(app)
        %%% Disable Start, Stop, and Restart buttons.
            app.StartButton.Enable = "off";
            app.StopButton.Enable = "off";
            app.ResumeButton.Enable = "off";

        %%% Disable all the plotting options except 'Geometry'.
            if strcmp(app.PlotTypeDropDown.Value, 'Geometry') == 0
                app.PlotButton.Enable = "off";
            end                
        end

        function UpdateGridInfo(app, GridFlag)
        %%% Update the grid spacing/scaling and number of points whenever any of the other values 
        %%% are changed.

            FaultLength = 1e3*app.FaultLengthkmEditField.Value;
            GeoCase = app.GeometryDropDown.Value;
            if strcmp(GeoCase, 'Landslide') == 1
            %%% Depth at the center of the slide.
                BurialDepth = 1e3*app.BurialDepthkmEditField.Value;

            %%% Height of the circle center.
                H_c = (1/(2*BurialDepth))*((FaultLength/2)^2 - BurialDepth^2);
                    
            %%% Radius of curvature.
                R = BurialDepth + H_c;
                        
            %%% Total arclength of the slide basal surface.
                s_1 = R*acos(FaultLength/(2*R));
                s_2 = R*acos(-FaultLength/(2*R));
                ArcLength = s_2 - s_1;
                FaultLength = ArcLength;
            end

            a = app.aEditField.Value;
            b = app.bEditField.Value;
            d_c = app.dcmEditField.Value;            
            ShearMod = 1e3*app.ShearModulusGPaEditField.Value;
            Poisson = app.PoissonRatioEditField.Value;
            
            UES_IC_Flag = false;
            EffStress_0 = UpdateEffectiveStress(app, UES_IC_Flag);

            H_s = pi*ShearMod*d_c/(EffStress_0*(1 - Poisson)*(b-a));
            L_b = d_c*ShearMod/(b*EffStress_0*(1 - Poisson));
            app.hkmLabel.Text = [sprintf('$h^*_F$ = %10.3f', H_s/1e3), ' \textsf{[km]}'];
            app.LbkmLabel.Text = [sprintf('$L_b$ = %10.3f', L_b/1e3), ' \textsf{[km]}'];

            switch(GridFlag)
                case 'Lbdxi'
                    Lbdxi = app.LbdxiEditField.Value;
                    app.dximEditField.Value = L_b/Lbdxi;
                    app.GridPointsEditField.Value = round(Lbdxi*FaultLength/L_b);
                case 'dxi'
                    dxi = app.dximEditField.Value;
                    app.LbdxiEditField.Value = L_b/dxi;
                    app.GridPointsEditField.Value = round(FaultLength/dxi);
                case 'N'
                    N = app.GridPointsEditField.Value;
                    app.dximEditField.Value = FaultLength/N ;
                    app.LbdxiEditField.Value = N*L_b/FaultLength;
                    % app.dximEditField.Value = FaultLength/(N - 1);
                    % app.LbdxiEditField.Value = (N-1)*L_b/FaultLength;
                % otherwise
            end           
        end
        
        function Vars_init = InitialConditions(app)
        %%% This function outputs an initial conditions vector, but does not store the vector in the 
        %%% parameters structure. The vector is stored by GetParameters and CreateGridButton.
        %%% This function is called by the StartButton via GetParameters(app) and also by the 
        %%% PlotButton and the CreateGridButton.

        %%% To avoid confusion, any change to an initial condition setting disables the controls 
        %%% and requires a new CreateGridButton press.       

        %%% Geometry.
            GeoCase = app.GeometryDropDown.Value;           

        %%% Fault coordinates.
            Xi = app.p.Geometry.FaultCoordinates;

        %%% Frictional parameters.
            a = app.aEditField.Value;
            b = app.bEditField.Value;
            d_c = app.dcmEditField.Value;
            v_ref = app.v0EditField.Value;
            mu_ref = app.mu0EditField.Value;

        %%% Initial effective normal stress.
            UES_IC_Flag = true;
            sigma_0 = UpdateEffectiveStress(app, UES_IC_Flag);
        %%% If the initial normal stress is variable, use the maximum value for subsequent
        %%% calculations of the other initial conditions.
            EffStress_0 = max(sigma_0);            

        %%% Initial slip velocity.
        %%% At plate rate steady state.
            v_init = app.InitialVelocitymsEditField.Value;
            v_var = app.RandomVariationEditField.Value;
            if v_var == 0
                v_0 = v_init*ones(size(Xi));
            else
                % v_0 = v_init*((rand(size(Xi), 1) + 0.5 - 1)/v_var + 1);
                p_var = 1e-2*v_var*(2*rand(size(Xi, 1), 1) - 1);
                v_0 = v_init*(p_var + 1);
            end

        %%% Identify the selected steady state button.
            SteadyStateOption = app.SteadyStateConditionButtonGroup.SelectedObject.Text;

            if strcmp(GeoCase, 'Landslide')
            %%% Initial shear stress due to gravity.
                beta = app.p.Geometry.FaultDip;
                Depth = app.p.Geometry.Depth;
                Density_S = app.SolidDensityEditField.Value;
                tau_0 = 1e-6*9.81*Density_S*Depth.*sind(beta);

            %%% Initial state variable at steady state for v_init. This ignores any random variation
            %%% that is added to v_init.
                % v_0 = (exp((tand(beta) - mu_ref)/(a-b)) - 1)*v_ref;
                % v_0(v_0 < 0) = 0;
                state_0 = (d_c./(v_0 + v_ref));
                % state_0 = (d_c./(v_init + v_ref)).*ones(size(Xi));

            %%% Initial friction.
                mu_0 = mu_ref + a*log((v_0 + v_ref)/v_ref) + b*log(v_ref*state_0/d_c);

            %%% Steady state slip.
                % ShearMod = 1e3*app.ShearModulusGPaEditField.Value;
                % Poisson = app.PoissonRatioEditField.Value;
                % K_s = (ShearMod/(2*pi*(1 - Poisson)))*app.p.Geometry.ShearKernel;
                % K_n = (ShearMod/(2*pi*(1 - Poisson)))*app.p.Geometry.NormalKernel;
                % 
                % % A = (ShearMod/(2*pi*(1 - Poisson)))*(T - mu_r*N);
                % A = K_s - mu_ref*K_n;
                % B = 1e-6*9.81*Density_S*Depth.*(mu_ref*cosd(beta) - sind(beta));
                % slip_0 = A\B;

            %%% No slip initial condition.
                slip_0 = zeros(size(Xi));

            else
                switch(SteadyStateOption)
                    case 'Shear Stress'
            %%% Steady state initial shear stress for a given initial velocity. This option can only
            %%% be accessed if the initial velocity is at steady state and therefore there is no 
            %%% elastic stress on the fault.
                    eta = app.p.Material.RadiationDamping;
                    tau_i = EffStress_0.*a*asinh((v_0./(2*v_ref)).*exp((mu_ref + b*log(v_ref./v_0))./a));
                    tau_0 = tau_i + eta*v_0;

            %%% Initial state variable that is consistent with the steady state initial shear stress.
                    state_0 = (d_c/v_ref)*exp((a./b).*log((2*v_ref./v_0)...
                        .*sinh(tau_i./(a*EffStress_0))) - mu_ref/b);
    
                    case 'State Variable'
            %%% Initial state variable at steady state for v_init. This ignores any random variation
            %%% that is added to v_init.
                    state_0 = (d_c./v_init).*ones(size(Xi));

            %%% Initial shear stress equal to the frictional stress at the initial velocity.
                    g = exp((mu_ref + b.*log(v_ref.*state_0./d_c))./a);
                    u = (v_0/(2*v_ref)).*g;
                    mu_0 = a.*asinh(u);
                    tau_0 = mu_0.*sigma_0;
                end
            end

        %%% Create the initial conditions vector.
            if strcmp(GeoCase, 'Landslide')
                % Vars_init = [v_0; tau_0; state_0; sigma_0; slip_0];
                Vars_init = [v_0; state_0; slip_0];
                % Vars_init = [v_0; state_0; slip_0; mu_0];
            else
                Vars_init = [v_0; tau_0; state_0; sigma_0];
            end

        %%% Add pore pressure.
            if app.SourceFunctionCheckBox.Value == 1 || app.DiffusionCheckBox.Value == 1

            %%% Initial excess pore pressure depends on the Effective Stress conditions. 
            %%% Get the stress case.
                selectedButton = app.EffectiveNormalStressButtonGroup.SelectedObject;
                if iscell(selectedButton.Text) == 1
                    StressCase = selectedButton.Text{1};
                else
                    StressCase = selectedButton.Text;
                end
    
                switch(StressCase)
                %%% For constant effective stress, get the initial excess pore pressure from the
                %%% edit field.
                    case 'Constant [MPa]'
                        p_init = app.InitialPorePressureEditField.Value;
                        pressure_0 = p_init*ones(size(Xi));
                    
                %%% If the effective stress is lithostatic, then the fault is "dry". I.E. the pore
                %%% pressure is zero and no fluid flow should be allowed?
                    % case 'Lithostatic Stress'
                    %     pressure_0 = zeros(N,1);
    
                    case 'Pore Pressure'
                        UES_IC_Flag = true;
                        [~, pressure_0] = UpdateEffectiveStress(app, UES_IC_Flag);
                end
                Vars_init = [Vars_init; pressure_0];
            end
        end

        
        function [EffStress0, Pressure0] = UpdateEffectiveStress(app, UES_IC_Flag)
        %%% This function computes:
        %%% UES_IC_Flag = false     the maximum effective normal stress on the fault
        %%% UES_IC_Flag = true      the effective normal stress on the fault elements.

        %%% If PorePressureRatio is selected, this function also outputs the initial
        %%% excess pressure on the fault.

        %%% This function is called by:
        %%% 1. DippingFaultSetUpUI (false) to compute grid spacing.
        %%% 2. UpdateGridInfo (false) to compute grid spacing.
        %%% 3. InitialConditions (true) to generate initial conditions.
        %%% 4. PlotTypeDrowDownValueChanged (false) to get y-axis limits.

        %%% UES_IC_Flag - logical flag that determines whether to output the initial
        %%% conditions vector or the maximum normal stress on the fault.

        %%% Get the geometry.
            GeoCase = app.GeometryDropDown.Value;

        %%% Dummy value for Pressure0. This will be overwritten when necessary.
            Pressure0 = nan;

        %%% 'Full Space' is selected so constant effective stress is the only option.
            if strcmp(GeoCase, 'Full Space') == 1
                EffStress0 = app.NormalStressMPaEditField.Value;
                if UES_IC_Flag
                    Xi = app.p.Geometry.FaultCoordinates;
                    EffStress0 = EffStress0*ones(size(Xi));
                end

        %%% A geometry other than 'Full Space' is selected, so the effective stress depends on the 
        %%% selected stress case.
            else
            %%% Get the stress case.
                selectedButton = app.EffectiveNormalStressButtonGroup.SelectedObject;
                if iscell(selectedButton.Text) == 1
                    StressCase = selectedButton.Text{1};
                else
                    StressCase = selectedButton.Text;
                end

            %%% Determine the effective stress depending on the stress case.
                switch(StressCase)
                    case 'Constant [MPa]'
                        EffStress0 = app.NormalStressMPaEditField.Value;
                        if UES_IC_Flag
                            Xi = app.p.Geometry.FaultCoordinates;
                            EffStress0 = EffStress0*ones(size(Xi));
                        end

                    case {'Lithostatic Stress', 'Pore Pressure'}                        
                    %%% Fault geometry.
                        FaultLength = 1e3*app.FaultLengthkmEditField.Value;
                        alpha = app.SurfaceSlopeEditField.Value;
                        beta = app.DipAngleEditField.Value;

                    %%% Burial depth - for faults, depth of updip edge, and for landslides,
                    %%% depth at the center of the slide.
                        BurialDepth = 1e3*app.BurialDepthkmEditField.Value;

                    %%% Solid density.
                        Density_S = app.SolidDensityEditField.Value;

                    %%% Zed is the depth below the upper surface of the grid coordinates.
                        if UES_IC_Flag                            
                            Zed = app.p.Geometry.Depth;
                            % Xi = app.p.Geometry.FaultCoordinates;
                            % Zed = BurialDepth + Xi*(sin(pi*beta/180)...
                            %     + cos(pi*beta/180)*tan(pi*alpha/180));

                            if strcmp(GeoCase, 'Landslide') == 1
                                beta = app.p.Geometry.FaultDip;
                            end
                        else
                    %%% Compute the maximum effective stress on the fault/landslide.
                    %%% EffStress0 is the effective stress at the down-dip edge [MPa].
                    %%% Zed is the depth below the upper surface of the fault's down-dip edge.
                            Zed = BurialDepth + FaultLength*(sin(pi*beta/180)...
                                + cos(pi*beta/180)*tan(pi*alpha/180));

                            if strcmp(GeoCase, 'Landslide') == 1
                    %%% Height of the circle center.
                                H_c = (1/(2*BurialDepth))*((FaultLength/2)^2 - BurialDepth^2);
                            
                    %%% Radius of curvature.
                                R = BurialDepth + H_c;
                                
                    %%% Find the value of the maximum normal stress by numerically finding
                    %%% the root of dNormalStress/dx. The function is defined at the end
                    %%% of UpdateEffectiveStress.
                                fun = @f;
                                s_1 = R*acos(FaultLength/(2*R));
                                x2 = real(R*cos((pi/180)*alpha + s_1/R) + H_c*sind(alpha)...
                                    + 1i*(R*sin((pi/180)*alpha + s_1/R) - H_c*cosd(alpha)));
                                x0 = [-x2 x2];
                                x_max = fzero(fun, x0);
                                
                                beta = atan((H_c*sind(alpha) - x_max)./...
                                    ((R^2 - (H_c*sind(alpha) - x_max).^2).^(1/2)));
                                beta = (180/pi)*beta;
                                
                                Zed = -(tand(alpha)*x_max - (R^2 - (H_c*sind(alpha)...
                                    - x_max).^2).^(1/2) + H_c*cosd(alpha));
                                
                                % EffStress0 = 1e-6*9.81*Density_S*zed_max*cos(beta_max);
                            end
                        end                                       

                    %%% Lithostatic stress.
                        if strcmp(StressCase, 'Lithostatic Stress') == 1
                            if strcmp(GeoCase, 'Lunar Thrust') == 1
                                EffStress0 = 1e-6*1.622*Density_S*Zed.*cosd(beta);
                            else
                                EffStress0 = 1e-6*9.81*Density_S*Zed.*cosd(beta);
                            end
                            
                    %%% For landslides, the effective normal stress depends on the slide
                    %%% geometry.
                    %         if strcmp(GeoCase, 'Landslide') == 1
                    % %%% Height of the circle center.
                    %             H_c = (1/(2*BurialDepth))*((FaultLength/2)^2 - BurialDepth^2);
                    % 
                    % %%% Radius of curvature.
                    %             R = BurialDepth + H_c;
                    % 
                    % %%% Find the value of the maximum normal stress by numerically finding
                    % %%% the root of dNormalStress/dx. The function is defined at the end
                    % %%% of UpdateEffectiveStress.
                    %             if UES_IC_Flag == 0
                    % 
                    %                 fun = @f;
                    %                 s_1 = R*acos(FaultLength/(2*R));
                    %                 x2 = real(R*cos((pi/180)*alpha + s_1/R) + H_c*sind(alpha)...
                    %                     + 1i*(R*sin((pi/180)*alpha + s_1/R) - H_c*cosd(alpha)));
                    %                 x0 = [-x2 x2];
                    %                 x_max = fzero(fun, x0);
                    % 
                    %                 beta_max = atan((H_c*sind(alpha) - x_max)./...
                    %                     ((R^2 - (H_c*sind(alpha) - x_max).^2).^(1/2)));
                    % 
                    %                 zed_max = -(tand(alpha)*x_max - (R^2 - (H_c*sind(alpha)...
                    %                     - x_max).^2).^(1/2) + H_c*cosd(alpha));
                    % 
                    %                 EffStress0 = 1e-6*9.81*Density_S*zed_max*cos(beta_max);
                    % 
                    %             else
                    % 
                    % %%% Compute the normal stress on the landslide grid.
                    %                 % Zed = p.
                    %                 EffStress0 = 1e-6*9.81*Density_S*Zed*cosd(beta);
                    %             end
                    % 
                    %         end

                    %%% Pore pressure ratio.
                        else
                            Density_F = app.FluidDensityEditField.Value;
                            PorePressureRatio = app.PorePressureRatioEditField.Value;

                            Stress_L = 9.81*Density_S*Zed;
                            Pressure_Hydro = 9.81*Density_F*Zed;
                            EffStress0 = 1e-6*(1 - PorePressureRatio)*(Stress_L - Pressure_Hydro);

                    %%% Excess pore pressure for initial conditions.
                            Pressure0 = 1e-6*PorePressureRatio*(Stress_L - Pressure_Hydro);
                        end
                end
            end

        %%% Update the effective stress label.
            if UES_IC_Flag == false
                app.EffectiveStressLabel.Text = sprintf('%10.3f [MPa]', EffStress0);
            end

        %%% Function for finding the maximum normal stress on a lanslide basal surface,
        %%% when it is defined as a circular arc.
            function dNdx = f(x)

                u = x - H_c*sind(alpha);
            
                dNdx = (9.81*Density_S*(R^2 - u.^2).^(3/2)/R)...
                    .*(u.*(H_c*cosd(alpha) - (R^2 - u.^2).^(1/2) + x*tand(alpha))./(R^2 - u.^2).^2 ...
                    - (u + (R^2 - u.^2).^(1/2)*tand(alpha))./(R^2 - u.^2).^(3/2));
            end
            
        end
        
        function EnableControls(app)
        %%% Enable Start, Stop, and Resume buttons.
            app.StartButton.Enable = "on";
            app.StopButton.Enable = "on";
            app.ResumeButton.Enable = "on";
        end
        
        function UpdateDiffusivity(app)
        %%% Update the displayed diffusivity value whenever one of the dependent parameter values is
        %%% changed.
            phi_0 = app.PorosityEditField.Value;
            Viscosity = app.FluidViscosityMPasEditField.Value;      % [MPa s]
            Compress = app.Compressibility1MPaEditField.Value;      % [1 / MPa]
            Perm = app.Permeabilitym2EditField.Value;               % [m^2]
            D_hydro = Perm/(phi_0*Compress*Viscosity);              % [m^2 / s]

            app.DiffusivityLabel.Text = ['\textsf{Diffusivity = }', sprintf('%10.3f', D_hydro),...
                ' \textsf{[m$^2$/s]}'];
        end
        
        function InitialPorePressureField(app)
        %%% This function enables or disables the InitialPorePressureEditField depending on the 
        %%% selected options. The funtion is called whenever the following options are changed:
        %%% 1. EffectiveNormalStressButtonGroupSelectionChanged
        %%% 2. SourceFunctionCheckBox
        %%% 3. DiffusionCheckBox

        %%% Get the stress case.
        selectedButton = app.EffectiveNormalStressButtonGroup.SelectedObject;
        if iscell(selectedButton.Text) == 1
            StressCase = selectedButton.Text{1};
        else
            StressCase = selectedButton.Text;
        end

        %%% Get the SourceFunctionCheckBox and DiffusionCheckBox values.
        valueS = app.SourceFunctionCheckBox.Value;
        valueD = app.DiffusionCheckBox.Value;
        
        switch StressCase
            
            case 'Constant [MPa]'
                if valueS || valueD            
                    app.InitialPorePressureEditField.Enable = 'on';
                else
                    app.InitialPorePressureEditField.Enable = 'off';
                end

            case {'Lithostatic Stress', 'Pore Pressure'}
                app.InitialPorePressureEditField.Enable = 'off';

        end
            
        end
    end % methods
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        %%% Parameters structure containing all necessary information to run a simulation.
            % app.p = struct('Geometry', [], 'Material', [], 'Friction', [], 'Options', []);

        %%% Set the app theme.
            % app.RSFaultZUIFigure.Theme.BaseColorStyle = "light";

        %%% Set the stop flag to 1, which means "don't stop".
            app.STOP = 1;

        %%% Labels with latex.
        %%% Effective Stress Tab.
            app.FluidDensitykgm3EditFieldLabel.Interpreter = 'latex';
            app.FluidDensitykgm3EditFieldLabel.Text = '\textsf{Fluid Density [kg/m$^3$]}';
            app.FluidDensitykgm3EditFieldLabel.FontSize = 11;
            app.SolidDensitykgm3EditFieldLabel.Interpreter = 'latex';
            app.SolidDensitykgm3EditFieldLabel.Text = '\textsf{Solid Density [kg/m$^3$]}';
            app.SolidDensitykgm3EditFieldLabel.FontSize = 11;
            app.LambdaLabel.Interpreter = 'latex';
            app.LambdaLabel.Text = '\textsf{($0 \leq \lambda \leq 1$)}';
            app.LambdaLabel.FontSize = 11;
            app.LambdaLabel.Enable = "off";

        %%% Fluid Flow Tab.
            app.Permeabilitym2Label.Interpreter = 'latex';
            app.Permeabilitym2Label.Text = [{'\textsf{Permeability}'}, {'\textsf{[m$^2$]}'}];
            app.Permeabilitym2Label.FontSize = 11;
            %%% DIFFUSIVITY.
            
        %%% Friction Parameters Panel.
            app.aEditFieldLabel.Interpreter = 'latex';
            app.aEditFieldLabel.Text = '$a$';
            app.bEditFieldLabel.Interpreter = 'latex';
            app.bEditFieldLabel.Text = '$b$';
            app.dcmLabel.Interpreter = 'latex';
            app.dcmLabel.Text = '$d_c$ \textsf{[m]}';
            app.dcmLabel.FontSize = 11;
            app.v0msLabel.Interpreter = 'latex';
            app.v0msLabel.Text = '$v_0$ \textsf{[m/s]}';
            app.v0msLabel.FontSize = 11;
            app.mu0EditFieldLabel.Interpreter = 'latex';
            app.mu0EditFieldLabel.Text = '$\mu_0$';

        %%% Grid Controls Panel.
            app.LbdxiEditFieldLabel.Interpreter = 'latex';
            app.LbdxiEditFieldLabel.Text = '$L_b / d\xi$';
            app.dximEditFieldLabel.Interpreter = 'latex';
            app.dximEditFieldLabel.Text = '$d\xi$ \textsf{[m]}';
            app.dximEditFieldLabel.FontSize = 11;
            
        %%% Populate the UI with values.
        %%% Geometry.
            app.GeometryDropDown.Value = 'Full Space';
            app.SurfaceSlopeEditField.Value = 0;
            app.DipAngleEditField.Value = 0;
            app.BurialDepthkmEditField.Value = 0;
            app.FaultLengthkmEditField.Value = 40;

        %%% Normal Stress.
            app.NormalStressMPaEditField.Value = 50;
            EffStress0 = app.NormalStressMPaEditField.Value;
            app.EffectiveStressLabel.Text = sprintf('%10.3f [MPa]', EffStress0);
            app.PorePressureRatioEditField.Value = 0;
            app.SolidDensityEditField.Value = 2650;
            app.FluidDensityEditField.Value = 1000;
            app.LithostaticStressButton.Enable = "off";
            app.PorePressureRatioButton.Enable = "off";
            app.PorePressureRatioEditField.Enable = "off";
            app.SolidDensityEditField.Enable = "off";
            app.FluidDensityEditField.Enable = "off";
            
        %%% Elastic.
            app.ShearModulusGPaEditField.Value = 32.04;
            app.PoissonRatioEditField.Value = 0;
            app.vsmsEditField.Value = 3464;

        %%% Fluid flow and pore pressure. Default parameters from BP6.
            app.SourceFunctionName.Value = 'BP6_SourceFunction';
            app.PorosityEditField.Value = 0.1;
            app.FluidViscosityMPasEditField.Value = 1e-6*1e-3;      % [MPa s]
            app.Compressibility1MPaEditField.Value = 1e6*1e-8;      % [1 / MPa]
            app.Permeabilitym2EditField.Value = 1e-13;              % [m^2]

            D_hydro = app.Permeabilitym2EditField.Value/(app.PorosityEditField.Value...
                *app.Compressibility1MPaEditField.Value*app.FluidViscosityMPasEditField.Value);
            app.DiffusivityLabel.Interpreter = 'latex';
            app.DiffusivityLabel.Text = ['\textsf{Diffusivity = }', sprintf('%10.3f', D_hydro),...
                ' \textsf{[m$^2$/s]}'];
            app.DiffusivityLabel.FontSize = 11;
            app.DiffusivityLabel.Enable = "off";

            app.SourceFunctionName.Enable = 'off';
            app.PorosityEditField.Enable = 'off';
            app.FluidViscosityMPasEditField.Enable = 'off';
            app.Compressibility1MPaEditField.Enable = 'off';
            app.Permeabilitym2EditField.Enable = 'off';
            app.InitialPorePressureEditField.Enable = 'off';

        %%% Friction.
            app.aEditField.Value = 0.007;
            app.bEditField.Value = 0.005;
            app.dcmEditField.Value = 0.004;
            app.v0EditField.Value = 1e-6;
            app.mu0EditField.Value = 0.6;

        %%% Numerical Grid.
            H_star = pi*1e3*app.ShearModulusGPaEditField.Value*app.dcmEditField.Value...
                /(app.NormalStressMPaEditField.Value*(1 - app.PoissonRatioEditField.Value)...
                *(app.bEditField.Value - app.aEditField.Value));
            L_b = 1e3*app.ShearModulusGPaEditField.Value*app.dcmEditField.Value...
                /(app.NormalStressMPaEditField.Value*(1 - app.PoissonRatioEditField.Value)...
                *app.bEditField.Value);

            app.hkmLabel.Interpreter = 'latex';            
            app.hkmLabel.Text = [sprintf('$h^*_F$ = %10.3f', H_star/1e3), ' \textsf{[km]}'];
            app.hkmLabel.FontSize = 11;
            app.LbkmLabel.Interpreter = 'latex';
            app.LbkmLabel.Text = [sprintf('$L_b$ = %10.3f', L_b/1e3), ' \textsf{[km]}'];
            app.LbkmLabel.FontSize = 11;
            app.LbdxiEditField.Value = 20;
            app.dximEditField.Value = L_b/app.LbdxiEditField.Value;
            app.GridPointsEditField.Value = round(1e3*app.FaultLengthkmEditField.Value/...
                app.dximEditField.Value);
            app.FourierSpaceCheckBox.Value = false;
            app.FourierSpaceCheckBox.Enable = 'off';

        %%% Simulation.
            app.RunTimeyrEditField.Value = 2;
            app.LoadingRatemsEditField.Value = 0;
            app.InitialVelocitymsEditField.Value = 1e-12;
            app.RandomVariationEditField.Value = 0;
            app.RandomVariationEditField.Limits = [0 100];

        %%% Export data structure name.
            app.StructureNameEditField.Value = 'SimData';

        %%% Disable Start, Stop, and Restart buttons.
            DisableControls(app);

        %%% Remove the axes toolbars and interactivity.
            app.VelAxes.Toolbar = [];
            app.GeoAxes.Toolbar = [];
            disableDefaultInteractivity(app.VelAxes)            
            disableDefaultInteractivity(app.GeoAxes)

        %%% Load the Moon map for plotting on the MoonAxes.
            filename = 'LunarCrustalThicknessMap.png';
            I = imread(filename);

        %%% Plot the .png file so that it is in the correct coordinates.
            lon = [-180 180];
            lat = [-90 90];
            image(app.MoonAxes, lon, lat, flip(I));

        %%% Add grid lines and axes labels.
            app.MoonAxes.YDir = "normal";
            app.MoonAxes.XLim = lon;
            app.MoonAxes.YLim = lat;
            app.MoonAxes.FontSize = 14;
            % app.MoonAxes.YLabel.String = 'Latitude';
            % app.MoonAxes.XLabel.String = 'Longitude';

            app.MoonAxes.TickLabelInterpreter = 'latex';
            app.MoonAxes.XTick = -180:30:180;
            app.MoonAxes.XTickLabel = [{'-180$^\circ$'}; {'-150$^\circ$'}; {'-120$^\circ$'};...
                {'-90$^\circ$'}; {'-60$^\circ$'}; {'-30$^\circ$'}; {'0$^\circ$'};...
                {'30$^\circ$'}; {'60$^\circ$'}; {'90$^\circ$'}; {'120$^\circ$'};...
                {'150$^\circ$'}; {'180$^\circ$'}];            
            app.MoonAxes.YTick = -90:30:90;
            app.MoonAxes.YTickLabel = [{'-90$^\circ$'}; {'-60$^\circ$'}; {'-30$^\circ$'};...
                {'0$^\circ$'}; {'30$^\circ$'}; {'60$^\circ$'}; {'90$^\circ$'}];
            
            grid(app.MoonAxes, 'on')
            app.MoonAxes.GridColor = 'k';
            app.MoonAxes.GridLineWidth = 1.5;
            app.MoonAxes.Layer = "top";

            app.TheMoonPanel.Enable = 'off';

            % % MoonMap_Fig = openfig('Lunar_SMQ_Map.fig', 'invisible');
            % MoonMap_Fig = openfig('Lunar_SMQ_Map2.fig', 'invisible');
            % MoonMap_Obj = MoonMap_Fig.CurrentAxes.Children;
            % copyobj(MoonMap_Obj, app.MoonAxes);
            % close(MoonMap_Fig);
            % 
            % app.MoonAxes.YDir = "reverse";
            % % axis equal
            % box(app.MoonAxes, 'on')
            % colormap(app.MoonAxes, "bone")
            % app.MoonAxes.FontSize = 14;
            % app.MoonAxes.XLim = [-180 180];
            % app.MoonAxes.YLim = [-90 90];
            % app.MoonAxes.YLabel.String = '';
            % app.MoonAxes.XLabel.String = '';
            % app.MoonAxes.TickLabelInterpreter = 'latex';
            % app.MoonAxes.XTick = -180:30:180;
            % app.MoonAxes.YTick = -90:30:90;
            % app.MoonAxes.XTickLabel = [{'-180$^\circ$'}; {'-150$^\circ$'}; {'-120$^\circ$'};...
            %     {'-90$^\circ$'}; {'-60$^\circ$'}; {'-30$^\circ$'}; {'0$^\circ$'};...
            %     {'30$^\circ$'}; {'60$^\circ$'}; {'90$^\circ$'}; {'120$^\circ$'};...
            %     {'150$^\circ$'}; {'180$^\circ$'}];
            % app.MoonAxes.YTickLabel = [{'90$^\circ$'}; {'60$^\circ$'}; {'30$^\circ$'};...
            %     {'0$^\circ$'}; {'-30$^\circ$'}; {'-60$^\circ$'}; {'-90$^\circ$'}];
            % % c = colorbar(app.MoonAxes, 'northoutside');
            % % c = colorbar(app.MoonAxes, 'eastoutside');
            % % c.FontSize = 12;
            % % c.TickLabelInterpreter = "latex";
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            GetParameters(app);
            app.STOP = 1;
            app.p.Options.Restart = 0;
        %%% Update the plots so that they can be turned on if the simulation is started with them 
        %%% turned off.
            PlotButtonPushed(app, event)
        %%% Start the timer.
            tic;
        %%% Run the simulation.
            app.Out = RunFaultSimUI(app);
            %toc; % uncomment this to print execution time to cmd window.
        end

        % Button pushed function: CreateGridButton
        function CreateGridButtonPushed(app, event)
        %%% Import the properties from the edit fields into the parameters structure.
            DippingFaultSetUpUI(app);

        %%% Display the number of grid points, and RSF length scales.
            app.GridPointsEditField.Value = app.p.Geometry.GridPoints;
            app.hkmLabel.Text = [sprintf('$h^*_F$ = %10.3f', app.p.Friction.H_star/1e3), ' \textsf{[km]}'];
            app.LbkmLabel.Text = [sprintf('$L_b$ = %10.3f', app.p.Friction.L_b/1e3), ' \textsf{[km]}'];

        %%% Disable SetProperties button.
            app.CreateGridButton.Enable = "off";

        %%% Create and store the initial conditions. If the Import flag is true, then the initial 
        %%% conditions must already be set. The point of this call to InitialConditions(app) is so 
        %%% that an initial conditions vector exists if CreateGridButton is pushed and then 
        %%% ExportButton is pushed without running a simulation. That way the exported structure has 
        %%% an initial conditions vector and can be imported later to run a simulation.
        %%% IS THERE ANY WAY app.p.Options.Import == 1 HERE? SINCE p IS CREATED BY DippingFaultSetUpUI(app).
            if app.p.Options.Import == 0
                Vars_init = InitialConditions(app);
                app.p.InitialConditions.Vars_init = Vars_init;
            end

        %%% Enable the Simulation Controls and the PlotButton if the PlotButton is on a valid option.
            switch(app.PlotTypeDropDown.Value)
                case 'Geometry'
                    EnableControls(app);

                case 'Shear Stress'
                    app.PlotButton.Enable = "on";
                    EnableControls(app);

                case 'Normal Stress'
                    switch(app.GeometryDropDown.Value)
                        case {'Thrust Fault', 'Normal Fault', 'Landslide'}
                            app.PlotButton.Enable = "on";
                            EnableControls(app);
                    end

                case 'Pore Pressure'
                    if app.p.Options.FluidDiffusion == true || app.p.Options.FluidSource == true
                        app.PlotButton.Enable = "on";
                        EnableControls(app);
                    end
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
        %%% 7.23.2025 - In dark mode it would be nice to make the tick mark colors black,
        %%% so that the show up on the gray background in the GeoAxes.
        
        %%% Maintain proper alignment for VelAxes and GeoAxes. This should only need to be called 
        %%% once, so should properly be in the startupFcn, but for some reason doesn't do anything 
        %%% there.
            app.VelAxes.PositionConstraint = 'innerposition';
            app.GeoAxes.PositionConstraint = 'innerposition';
            
        %%% Clear the axes in case something was already plotted.
            cla(app.GeoAxes);

            switch(app.PlotTypeDropDown.Value)
                case 'Geometry'
                    
                    switch(app.GeometryDropDown.Value)
                        case 'Full Space'
                            FaultLength = app.FaultLengthkmEditField.Value;
                            x_max = FaultLength/2;
                            x_min = -FaultLength/2;
                        %%% Area plot of the upper half-space.
                            area(app.GeoAxes, [x_min,x_max], [x_max,x_max], 'FaceColor', '#BEBEBE')
                            hold(app.GeoAxes, 'on');
                        %%% Area plot of the lower half-space.
                            area(app.GeoAxes, [x_min,x_max], [x_min,x_min], 'FaceColor', '#BEBEBE')
                        %%% Plot the fault surface.
                            plot(app.GeoAxes, [x_min; x_max], [0; 0], 'r', 'LineWidth', 2)
                        %%% Proper axes limits.
                            xlim(app.GeoAxes, [x_min x_max])
                            ylim(app.GeoAxes, [x_min x_max])
                            hold(app.GeoAxes, 'off')
                            axis(app.GeoAxes, 'normal')
                            

                        case {'Thrust Fault', 'Normal Fault', 'Strike Slip'}
                            alpha = app.SurfaceSlopeEditField.Value;
                            beta = app.DipAngleEditField.Value;
                            FaultLength = app.FaultLengthkmEditField.Value;
                            z_burial = app.BurialDepthkmEditField.Value;
                            z_fault = z_burial + FaultLength*sin(pi*beta/180);
                            z_upper = -FaultLength*cos(pi*beta/180)*tan(pi*alpha/180);
                        %%% Abosolute value for strike-slip faults with beta > 90.
                        %%% This may cause problems for dip-slip faults.
                            x_min = 0;
                            x_max = FaultLength*abs(cos(pi*beta/180));
                        
                            if alpha == 0 && beta ~= 0
                                area(app.GeoAxes, [0, x_max], [0, z_upper], z_fault, 'FaceColor', '#BEBEBE')
                                ylim(app.GeoAxes, [-0.1*z_fault z_fault])
            
                            elseif z_burial ~= 0 && beta == 0
                                area(app.GeoAxes, [0, x_max], [0, z_upper], 2*z_fault, 'FaceColor', '#BEBEBE')
                                ylim(app.GeoAxes, [z_upper-0.1*z_fault 2*z_fault])
            
                            else
                                area(app.GeoAxes, [0, x_max], [0, z_upper], z_fault, 'FaceColor', '#BEBEBE')
                                ylim(app.GeoAxes, [z_upper z_fault])
                            end
                            hold(app.GeoAxes, 'on');
            
                        %%% Plot the upper surface.
                            plot(app.GeoAxes, [0; x_max], [0; z_upper], 'k', 'LineWidth', 2)
                            
                        %%% Plot the fault surface
                            plot(app.GeoAxes, [0; x_max], [z_burial; z_fault], 'r', 'LineWidth', 2)
                            xlim(app.GeoAxes, [0 x_max])
            
                            set(app.GeoAxes, 'YDir', 'reverse');
                            hold(app.GeoAxes, 'off')
                            axis(app.GeoAxes, 'normal')


                        case 'Lunar Thrust'
                        %%% Mean radius of the Moon.
                            R = 1737.4;                               % [km]

                        %%% Length of the fault and the dip angle
                            beta = app.DipAngleEditField.Value;
                            FaultLength = app.FaultLengthkmEditField.Value;

                        %%% depth below a horizontal surface extending from theta = pi/2.
                            z_burial = app.BurialDepthkmEditField.Value;
                            z_fault = z_burial + FaultLength*sin(pi*beta/180);

                        %%% Horizontal locations of the fault edges.
                            x_min = 0;
                            x_max = FaultLength*abs(cos(pi*beta/180));

                        %%% Coordinates of the arc of the Moon's surface that contains the 
                        %%% fault.
                            Theta_FaultArc = linspace(pi/2. ... 
                                - atan(FaultLength/(R - FaultLength*sin((pi/180)*beta))),...
                                pi/2, 100)';
                            Z_FaultArc = R*exp(1i*Theta_FaultArc);
                            Z_FaultArc = complex(real(Z_FaultArc), -(imag(Z_FaultArc) - R));

                        %%% Plot the elastic area.
                            area(app.GeoAxes, real(Z_FaultArc), imag(Z_FaultArc), z_fault, "FaceColor", '#BEBEBE')
                            hold(app.GeoAxes, 'on');
                        
                        %%% Plot the fault surface
                            plot(app.GeoAxes, [0; x_max], [z_burial; z_fault], 'r', 'LineWidth', 2)

                            xlim(app.GeoAxes, [0 x_max])
                            ylim(app.GeoAxes, [-0.1*z_fault z_fault])
                            set(app.GeoAxes, 'YDir', 'reverse');
                            hold(app.GeoAxes, 'off')
                            axis(app.GeoAxes, 'normal')


                        case 'Landslide'
                            alpha = app.SurfaceSlopeEditField.Value;
                            FaultLength = app.FaultLengthkmEditField.Value;
                            z_burial = app.BurialDepthkmEditField.Value;
                           
                        %%% Height of the circle center.
                            H_c = (1/(2*z_burial))*((FaultLength/2)^2 - z_burial^2);
                            
                        %%% Radius of curvature.
                            R = z_burial + H_c;
                            
                        %%% Arc length goes in the direction of the polar angle.
                            s_1 = R*acos(FaultLength/(2*R));
                            s_2 = R*acos(-FaultLength/(2*R));
                            
                        %%% Number of points to use for the plot. Arclength along the
                        %%% slide basal surface.
                            N_plot = 1000;
                            S = linspace(s_1, s_2, N_plot)';
                            
                        %%% Along slide length goes in the opposite direction and starts at zero. The up-slope
                        %%% edge of the slide is at z = -L/2, and down-slope edge is at z = L/2 so that x 
                        %%% increases as one moves down-slope along the slide surface.

                        %%% Complex coordinates as a fuction of arc length.
                            Z_arclength = R*cos(S/R) + 1i*(R*sin(S/R) - H_c);
                            
                        %%% Complex coordinates as a fuction of along-slope coordintes.
                            Z = flip(Z_arclength);

                        %%% Rotate coordinates.
                            Rot = exp(1i*(pi/180)*alpha);
                            Z = Rot*Z;

                        %%% Plot the infinite hill slope. Round the x-axis limits to
                        %%% nearest hundred meters.
                            X = round(max(real(Z)), 1)*[-1; 1];
                            x_min = min(X);
                            x_max = max(X);
                            Y = X*tand(alpha);
                            area(app.GeoAxes, X, Y, Y(2), FaceColor = "#993300", EdgeColor = "none")
                            hold(app.GeoAxes, 'on');                           
                            
                        %%% Plot the sliding surface.
                            plot(app.GeoAxes, real(Z), imag(Z), 'g', LineWidth = 2);

                        %%% Axes and limits.
                            set(app.GeoAxes, 'YDir', 'reverse');
                            hold(app.GeoAxes, 'off')
                            app.GeoAxes.XLim = [x_min x_max];
                            axis(app.GeoAxes, 'equal')

                        %%% Fill in the extra space created by equalizing the axes.
                            % area(app.GeoAxes, app.GeoAxes.XLim, app.GeoAxes.YLim(2)*[1 1],...
                            %     Y(2), FaceColor = "#993300", EdgeColor = "none")

                    end                   

         %%% On fault quantities can't be plotted until a grid exists. But changing the initial 
         %%% conditions does not require a new grid. So generate the initial conditions here, in 
         %%% case they have been changed after the grid has been created. This needs to be
         %%% overridden if a simulation has been imported.
            case {'Shear Stress', 'Normal Stress', 'Pore Pressure'}
                %%% Get the initial conditions on the fault.
                    N = app.p.Geometry.GridPoints;
                    if app.p.Options.Import == 0
                        Vars_0 = InitialConditions(app);
                    else
                        Vars_0 = app.p.InitialConditions.Vars_init;
                    end

                %%% Get the fault coordinates.
                    switch(app.GeometryDropDown.Value)
                        case 'Full Space'
                            X = app.p.Geometry.FaultCoordinates;
                            FaultLength = app.FaultLengthkmEditField.Value;
                            x_max = FaultLength/2;
                            x_min = -FaultLength/2;

                        case {'Thrust Fault', 'Normal Fault', 'Strike Slip', 'Lunar Thrust'}
                            X = 1e-3*app.p.Geometry.Distance;
                            x_min = 0;
                            x_max = X(end);
                    end
                
                %%% Get the appropriate variable from the initial conditions vector.
                    switch(app.PlotTypeDropDown.Value)
                        case 'Shear Stress'
                            XData = Vars_0(N+1:2*N);
                        case 'Normal Stress'
                            XData = Vars_0(3*N+1:4*N);
                        case 'Pore Pressure'
                            XData = Vars_0(4*N+1:5*N);
                    end

                %%% Plot the initial shear stress.
                    set(app.GeoAxes, 'YDir', 'normal');
                    plot(app.GeoAxes, X, XData, 'b', 'LineWidth', 2)                           
                    xlim(app.GeoAxes, [x_min x_max])
                    y_min = app.yminEditField.Value;
                    y_max = app.ymaxEditField.Value;
                    ylim(app.GeoAxes, [y_min y_max])
            end

            %%% Set VelAxes limits to speed up drawnow calls.
                set(app.VelAxes, 'YScale', 'log')
                xlim(app.VelAxes, [x_min x_max])
                ylim(app.VelAxes, [1e-15 1e0])
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.STOP = 0;
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
        %%% 5.19.2025 - MOVE THIS TO InitialConditions(app), why not?
            Vars_init = InitialConditions(app);
            app.p.InitialConditions.Vars_init = Vars_init;
            
            v_init = app.InitialVelocitymsEditField.Value;
            app.p.InitialConditions.InitialVelocity = v_init;

            SimData = struct('Params', app.p, 'Output', app.Out);
            DataName = app.StructureNameEditField.Value;
            assignin('base', DataName, SimData);
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
        %%% CURRENTLY BROKEN FOR PORE PRESSURE.
            app.STOP = 1;
            app.p.Options.Restart = 1;
            RunTime = app.RunTimeyrEditField.Value;
            time = [0 RunTime];
            % app.p.Geometry.Runtime = time;
            app.p.Options.RunTime = time;
            tic;

        %%% app.Out should already exist, since this is a restart. So stitch the restart Out onto
        %%% the current app.Out
            Out_R = RunFaultSimUI(app);
            app.Out.NormalStress = [app.Out.NormalStress; Out_R.NormalStress(2:end,:)];
            app.Out.ShearStress = [app.Out.ShearStress; Out_R.ShearStress(2:end,:)];
            app.Out.State = [app.Out.State; Out_R.State(2:end,:)];
            app.Out.Vel = [app.Out.Vel; Out_R.Vel(2:end,:)];
            app.Out.Time = [app.Out.Time; Out_R.Time(2:end,:) + app.Out.Time(end)];
            app.Out.Slip = [app.Out.Slip; Out_R.Slip(2:end,:) + app.Out.Slip(end,:)];
            app.Out.Final = Out_R.Final;
            if app.p.Options.FluidDiffusion || app.p.Options.FluidSource
                app.Out.PorePressure = [app.Out.PorePressure; Out_R.PorePressure(2:end,:)];
            end
        end

        % Value changed function: dcmEditField
        function dcmEditFieldValueChanged(app, event)
            % value = app.dcmEditField.Value;
            app.CreateGridButton.Enable = "on";
            DisableControls(app);
            UpdateGridInfo(app, 'Lbdxi');
        end

        % Value changed function: bEditField
        function bEditFieldValueChanged(app, event)
            % value = app.bEditField.Value;
            app.CreateGridButton.Enable = "on";
            DisableControls(app);
            UpdateGridInfo(app, 'Lbdxi');
        end

        % Value changed function: ShearModulusGPaEditField
        function ShearModulusGPaEditFieldValueChanged(app, event)
            % value = app.ShearModulusGPaEditField.Value;
            app.CreateGridButton.Enable = "on";
            DisableControls(app);
            UpdateGridInfo(app, 'Lbdxi');
        end

        % Value changed function: PoissonRatioEditField
        function PoissonRatioEditFieldValueChanged(app, event)
            % value = app.PoissonRatioEditField.Value;
            app.CreateGridButton.Enable = "on";
            DisableControls(app);
            UpdateGridInfo(app, 'Lbdxi');
        end

        % Value changed function: NormalStressMPaEditField
        function NormalStressMPaEditFieldValueChanged(app, event)
            % value = app.NormalStressMPaEditField.Value;
            app.CreateGridButton.Enable = "on";
            DisableControls(app);
            UpdateGridInfo(app, 'Lbdxi');
        end

        % Value changed function: FaultLengthkmEditField
        function FaultLengthkmEditFieldValueChanged(app, event)
            % value = app.FaultLengthkmEditField.Value;
            app.CreateGridButton.Enable = "on";
            DisableControls(app);
            UpdateGridInfo(app, 'dxi');
        end

        % Value changed function: SurfaceSlopeEditField
        function SurfaceSlopeEditFieldValueChanged(app, event)
            % value = app.SurfaceSlopeEditField.Value;
            app.CreateGridButton.Enable = "on";
            DisableControls(app);        
            UpdateGridInfo(app, 'Lbdxi');
        end

        % Value changed function: DipAngleEditField
        function DipAngleEditFieldValueChanged(app, event)
            % value = app.DipAngleEditField.Value;
            app.CreateGridButton.Enable = "on";
            DisableControls(app);
            UpdateGridInfo(app, 'Lbdxi');
        end

        % Value changed function: BurialDepthkmEditField
        function BurialDepthkmEditFieldValueChanged(app, event)
            % value = app.BurialDepthkmEditField.Value;
            app.CreateGridButton.Enable = "on";
            DisableControls(app);
            UpdateGridInfo(app, 'Lbdxi');            
        end

        % Value changed function: GeometryDropDown
        function GeometryDropDownValueChanged(app, event)
            value = app.GeometryDropDown.Value;

        %%% 3.5.2026 - A lot of these commands to enable/disable things on the
        %%% EffectiveNormalStressButtonGroup are meant to be handled by 
        %%% EffectiveNormalStressButtonGroupSelectionChanged, which currently changes
        %%% some of the selections, then they are changed again by commands here.
            switch(value)
                case 'Full Space'
                    app.FourierSpaceCheckBox.Enable = "on";
                    app.CreateGridButton.Enable = "on";
                    DisableControls(app);
                    app.BurialDepthkmEditField.Enable = "off";
                    app.DipAngleEditField.Enable = "off";
                    app.SurfaceSlopeEditField.Enable = "off";
                    app.PoissonRatioEditField.Enable = "on";

                    app.LithostaticStressButton.Enable = "off";
                    app.PorePressureRatioButton.Enable = "off";
                    app.LambdaLabel.Enable = "off";
                    app.PorePressureRatioEditField.Enable = "off";
                    app.SolidDensityEditField.Enable = "off";
                    app.FluidDensityEditField.Enable = "off";

                    app.ConstantMPaButton.Enable = "on";
                    app.NormalStressMPaEditField.Enable = "on";
                    app.EffectiveNormalStressButtonGroup.Buttons(1,1).Value = true;

                    app.TheMoonPanel.Enable = "off";

                case {'Thrust Fault', 'Normal Fault'}
                    app.FourierSpaceCheckBox.Enable = "off";
                    app.CreateGridButton.Enable = "on";
                    DisableControls(app);
                    app.BurialDepthkmEditField.Enable = "on";
                    app.DipAngleEditField.Enable = "on";
                    app.SurfaceSlopeEditField.Enable = "on";
                    app.PoissonRatioEditField.Enable = "on";

                    app.ConstantMPaButton.Enable = "on";
                    app.NormalStressMPaEditField.Enable = "on";
                    app.LithostaticStressButton.Enable = "on";
                    app.PorePressureRatioButton.Enable = "on";
                    app.LambdaLabel.Enable = "on";

                    app.TheMoonPanel.Enable = "off";

            %%% Enable the Fluid Flow Tab check boxes.
                    app.SourceFunctionCheckBox.Enable = "on";
                    app.DiffusionCheckBox.Enable = "on";

                case 'Strike Slip'
                    app.FourierSpaceCheckBox.Enable = "off";
                    app.CreateGridButton.Enable = "on";
                    DisableControls(app);
                    app.BurialDepthkmEditField.Value = 0;
                    app.BurialDepthkmEditField.Enable = "off";
                    app.DipAngleEditField.Enable = "on";
                    app.SurfaceSlopeEditField.Value = 0;
                    app.SurfaceSlopeEditField.Enable = "off";
                    app.PoissonRatioEditField.Value = 0;
                    app.PoissonRatioEditField.Enable = "off";

                    app.ConstantMPaButton.Enable = "on";
                    app.NormalStressMPaEditField.Enable = "on";
                    app.LithostaticStressButton.Enable = "on";
                    app.PorePressureRatioButton.Enable = "on";
                    app.LambdaLabel.Enable = "on";

                    app.TheMoonPanel.Enable = "off";

            %%% Enable the Fluid Flow Tab check boxes.
                    app.SourceFunctionCheckBox.Enable = "on";
                    app.DiffusionCheckBox.Enable = "on";

                case 'Landslide'
                    app.FaultLengthkmEditField.Value = 1.4;
                    app.FourierSpaceCheckBox.Enable = "off";
                    app.CreateGridButton.Enable = "on";
                    DisableControls(app);
                    app.BurialDepthkmEditField.Enable = "on";
                    app.BurialDepthkmEditField.Value = 0.008;
                    app.DipAngleEditField.Value = 0;
                    app.DipAngleEditField.Enable = "off";
                    app.SurfaceSlopeEditField.Enable = "on";
                    app.SurfaceSlopeEditField.Value = 16;
                    app.PoissonRatioEditField.Enable = "on";
                    app.PoissonRatioEditField.Value = 1/3;
                    app.ShearModulusGPaEditField.Value = 0.211;
                    app.vsmsEditField.Value = 300;

                %%% Parameter values from Finnegan & Saffer (2024) for OREO.
                    app.aEditField.Value = 0.011;
                    app.bEditField.Value = 0.001;
                    app.dcmEditField.Value = 1e-4;
                    app.mu0EditField.Value = 0.32;
                    spd = 24*3600;
                    % app.v0EditField.Value = 1e-3/spd;
                    app.v0EditField.Value = 1e-7;
                    app.InitialVelocitymsEditField.Value = 0;

                    app.ConstantMPaButton.Enable = "off";
                    app.LithostaticStressButton.Enable = "on";
                    app.EffectiveNormalStressButtonGroup.Buttons(1,3).Value = true;                    
                    app.SolidDensityEditField.Value = 2344;
                    EffectiveNormalStressButtonGroupSelectionChanged(app, event)
                    app.PorePressureRatioButton.Enable = "on";
                    app.LambdaLabel.Enable = "on";

                    app.TheMoonPanel.Enable = "off";

            %%% Enable the Fluid Flow Tab check boxes.
                    app.SourceFunctionCheckBox.Enable = "on";
                    app.DiffusionCheckBox.Enable = "on";

                case 'Lunar Thrust'
                    app.FourierSpaceCheckBox.Enable = "off";
                    app.CreateGridButton.Enable = "on";
                    DisableControls(app);
                    
                    app.SurfaceSlopeEditField.Value = 0;
                    app.SurfaceSlopeEditField.Enable = "off";
                    app.DipAngleEditField.Enable = "on";
                    app.BurialDepthkmEditField.Enable = "on";

                    app.PoissonRatioEditField.Enable = "on";

                    app.ConstantMPaButton.Enable = "off";
                    app.LithostaticStressButton.Enable = "on";
                    app.LithostaticStressButton.Enable = "on";
                    app.PorePressureRatioButton.Enable = "off";
                    app.LambdaLabel.Enable = "off";

        %%% Uncheck the Fluid Flow Tab check boxes and disable them.
                    app.SourceFunctionCheckBox.Value = 0;
                    app.DiffusionCheckBox.Value = 0;
                    SourceFunctionCheckBoxValueChanged(app, event);
                    DiffusionCheckBoxValueChanged(app, event);

                    app.SourceFunctionCheckBox.Enable = "off";
                    app.DiffusionCheckBox.Enable = "off";

                    app.TheMoonPanel.Enable = "on";

            end
        
        %%% Update the grid info whenever the geometry to changed. CURRENTLY CAUSING
        %%% ERRORS: SEE ISSUE 90.
            % UpdateGridInfo(app, 'dxi')
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
        %%% Import a parameter structure from the workspace.

        %%% Create a structure array using the name that is entered in the import field.
            p_import = evalin('base', app.ImportEditField.Value);
            app.p = p_import;
            
        %%% Display the properties from the imported parameter structure.
        %%% Geometry Panel.
            app.FaultLengthkmEditField.Value = app.p.Geometry.FaultLength/1e3;
            app.GeometryDropDown.Value = app.p.Options.Geometry;
            GeometryDropDownValueChanged(app, event);
            EnableControls(app);

            switch(app.p.Options.Geometry)

                case 'Full Space'
                    app.FourierSpaceCheckBox.Value = app.p.Options.FourierSpace;

                case {'Thrust Fault', 'Normal Fault'}                   
                    app.DipAngleEditField.Value = app.p.Geometry.FaultDip;
                    app.SurfaceSlopeEditField.Value = app.p.Geometry.SurfaceSlope;
                    app.BurialDepthkmEditField.Value = app.p.Geometry.BurialDepth/1e3;

                case 'Strike Slip'
                    app.DipAngleEditField.Value = app.p.Geometry.FaultDip;
                    app.SurfaceSlopeEditField.Value = app.p.Geometry.SurfaceSlope;
                    app.BurialDepthkmEditField.Value = 0;
            end

        %%% Plot the geometry. THIS HAS TO BE EDITED FOR THE NEW IMPORT OPTIONS TO AVOID
        %%% OVERWRITING.
            % PlotButtonPushed(app, []);

        %%% Effective stress.
            StressCase = app.p.Options.EffectiveStress;
            switch StressCase
                case 'Constant'
                    app.NormalStressMPaEditField.Value = app.p.Material.BackgroundStress;
                    app.ConstantMPaButton.Value = true;

                case 'Lithostatic'
                    app.SolidDensityEditField.Value = app.p.Material.SolidDensity;
                    app.LithostaticStressButton.Value = true;

                case 'Pore Pressure Ratio'                    
                    app.SolidDensityEditField.Value = app.p.Material.SolidDensity;
                    app.FluidDensityEditField.Value = app.p.Material.FluidDensity;
                    app.PorePressureRatioEditField.Value = app.p.Material.PorePressureRatio;
                    app.PorePressureRatioButton.Value = true;
            end
            EffectiveNormalStressButtonGroupSelectionChanged(app, event);

        %%% Elastic Parameters Panel.        
            if strcmp(app.p.Options.Geometry, 'Strike Slip') == 1
                app.PoissonRatioEditField.Value = 0;
            else
                app.PoissonRatioEditField.Value = app.p.Material.PoissonRatio;
            end
            app.ShearModulusGPaEditField.Value = app.p.Material.ShearModulus/1e3;
            app.vsmsEditField.Value = app.p.Material.ShearWaveSpeed;

        %%% Fluid flow options. Currently written for constant parameter values.
            if app.p.Options.FluidDiffusion
                app.DiffusionCheckBox.Value = 1;
                DiffusionCheckBoxValueChanged(app, event);
                app.Compressibility1MPaEditField.Value = app.p.FluidFlow.PoreFluidCompress;
                app.Permeabilitym2EditField.Value = app.p.FluidFlow.Permeability;
                app.FluidViscosityMPasEditField.Value = app.p.FluidFlow.FluidViscosity;
                app.PorosityEditField.Value = app.p.FluidFlow.Porosity_ref;
                UpdateDiffusivity(app);
            else
                app.DiffusionCheckBox.Value = 0;
                DiffusionCheckBoxValueChanged(app, event);
            end

        %%% Fluid source function options.
            if app.p.Options.FluidSource
                app.SourceFunctionCheckBox.Value = 1;
                SourceFunctionCheckBoxValueChanged(app, event);

            %%% Get the name of the source function. It will always contain a nested function.
                sh = functions(app.p.FluidFlow.SourceFunction);
                FuncName = sh.function;
                k = strfind(FuncName, '/');
                FuncName = FuncName(1:k-1);
                app.SourceFunctionName.Value = FuncName;
            else
                app.SourceFunctionCheckBox.Value = 0;
                SourceFunctionCheckBoxValueChanged(app, event);
            end

        %%% Friction Parameters Panel.
        %%% For variable RSF parameters display the most unstable values, i.e. smallest H_s_F:
        %%% H_s_F = pi*d_c*G/(sigma*(b-a)).
            app.mu0EditField.Value = app.p.Friction.mu_ref;
            app.v0EditField.Value = app.p.Friction.v_ref;
            app.dcmEditField.Value = min(app.p.Friction.d_c);
            app.bEditField.Value = max(app.p.Friction.b);        
            app.aEditField.Value = min(app.p.Friction.a);

        %%% Grid Controls Panel.
            app.GridPointsEditField.Value = app.p.Geometry.GridPoints;
            app.dximEditField.Value = app.p.Geometry.GridSpacing;

        %%% If L_b varies, display and use the smallest value.
            app.LbdxiEditField.Value = min(app.p.Friction.L_b)/app.p.Geometry.GridSpacing;
            app.LbkmLabel.Text = [sprintf('$L_b$ = %10.3f', min(app.p.Friction.L_b)/1e3), ' \textsf{[km]}'];
            app.hkmLabel.Text = [sprintf('$h^*_F$ = %10.3f', min(app.p.Friction.H_star)/1e3), ' \textsf{[km]}'];
            
        %%% Initial conditions. Some of these options might not be set for custom simulations. But 
        %%% need to be specified for parameters structures generated from the GUI.        
            % app.LoadingRatemsEditField.Value = app.p.Material.PlateRate;
            app.LoadingRatemsEditField.Value = app.p.InitialConditions.PlateRate;

        %%% Simulation Controls Panel.
            app.StateLawDropDown.Value = app.p.Options.StateLaw;
            % app.RunTimeyrEditField.Value = app.p.Geometry.Runtime(1,2);
            app.RunTimeyrEditField.Value = app.p.Options.RunTime(1,2);
        
        %%% Disable CreateGridButton.
            app.CreateGridButton.Enable = "off";

        %%% Enable Start, Stop, and Restart buttons.
            app.StartButton.Enable = "on";
            app.StopButton.Enable = "on";
            app.ResumeButton.Enable = "on";
            
        end

        % Value changed function: LbdxiEditField
        function LbdxiEditFieldValueChanged(app, event)
            % value = app.LbdxiEditField.Value;
            app.CreateGridButton.Enable = "on";
            DisableControls(app);
            UpdateGridInfo(app, 'Lbdxi');
        end

        % Value changed function: dximEditField
        function dximEditFieldValueChanged(app, event)
            % value = app.dximEditField.Value;
            app.CreateGridButton.Enable = "on";
            DisableControls(app);
            UpdateGridInfo(app, 'dxi');
        end

        % Value changed function: GridPointsEditField
        function GridPointsEditFieldValueChanged(app, event)
            % value = app.GridPointsEditField.Value;
            app.CreateGridButton.Enable = "on";
            DisableControls(app);
            UpdateGridInfo(app, 'N');
        end

        % Value changed function: aEditField
        function aEditFieldValueChanged(app, event)
            % value = app.aEditField.Value;
            UpdateGridInfo(app, '');
        end

        % Selection changed function: EffectiveNormalStressButtonGroup
        function EffectiveNormalStressButtonGroupSelectionChanged(app, event)
            selectedButton = app.EffectiveNormalStressButtonGroup.SelectedObject;
            if iscell(selectedButton.Text) == 1
                StressCase = selectedButton.Text{1};
            else
                StressCase = selectedButton.Text;
            end

            switch(StressCase)
                case 'Constant [MPa]'
                    app.NormalStressMPaEditField.Enable = "on";
                    app.PorePressureRatioEditField.Enable = "off";
                    app.SolidDensityEditField.Enable = "off";
                    app.FluidDensityEditField.Enable = "off";
                %%% Make sure fluid flow options are enabled, but not if "Lunar Thrust" is
                %%% selected
                    if strcmp(app.GeometryDropDown.Value, 'Lunar Thrust') == 0
                        app.SourceFunctionCheckBox.Enable = "on";
                        app.DiffusionCheckBox.Enable = "on";
                    end

                case 'Lithostatic Stress'
                    if strcmp(app.GeometryDropDown.Value, 'Full Space') == 0
                        app.NormalStressMPaEditField.Enable = "off";
                        app.PorePressureRatioEditField.Enable = "off";
                        app.SolidDensityEditField.Enable = "on";
                        app.FluidDensityEditField.Enable = "off";

                    %%% Can only have lithostatic effective stress is there is no fluid,
                    %%% so disable fluid flow options.
                        app.SourceFunctionCheckBox.Value = false;
                        app.SourceFunctionCheckBox.Enable = "off";
                        app.DiffusionCheckBox.Value = false;
                        app.DiffusionCheckBox.Enable = "off";
                        SourceFunctionCheckBoxValueChanged(app, event);
                        DiffusionCheckBoxValueChanged(app, event);
                    end

                case 'Pore Pressure'
                    if strcmp(app.GeometryDropDown.Value, 'Full Space') == 0
                        app.NormalStressMPaEditField.Enable = "off";
                        app.PorePressureRatioEditField.Enable = "on";
                        app.SolidDensityEditField.Enable = "on";
                        app.FluidDensityEditField.Enable = "on";
                    %%% Make sure fluid flow options are enabled.
                        app.SourceFunctionCheckBox.Enable = "on";
                        app.DiffusionCheckBox.Enable = "on";
                    end
            end

        %%% Enable or disable the InitialPorePressureEditField as appropriate.
            InitialPorePressureField(app);

        %%% Whenever one of the buttons is selected, or a value is changed.
            app.CreateGridButton.Enable = "on";
            DisableControls(app);
            UpdateGridInfo(app, 'Lbdxi');
        end

        % Value changed function: PorePressureRatioEditField
        function PorePressureRatioEditFieldValueChanged(app, event)
            % value = app.PorePressureRatioEditField.Value;
            app.CreateGridButton.Enable = "on";
            DisableControls(app);
            UpdateGridInfo(app, 'Lbdxi');
        end

        % Value changed function: FourierSpaceCheckBox
        function FourierSpaceCheckBoxValueChanged(app, event)
            % value = app.FourierSpaceCheckBox.Value;
            app.CreateGridButton.Enable = "on";
            DisableControls(app);
        end

        % Callback function
        function vsmsEditFieldValueChanged(app, event)
            value = app.vsmsEditField.Value;
            % UpdateGridInfo(app, '');
        end

        % Value changed function: PlotTypeDropDown
        function PlotTypeDropDownValueChanged(app, event)
            value = app.PlotTypeDropDown.Value;

        %%% Once the grid is created the initial conditions are created as well. So, enable
        %%% plotting of initial simulation variables based on the geometry and simulation options.
        %%% To avoid confusion, these plotting options are only turned on if the "Create Grid" 
        %%% button is disabled.

        %%% Does a grid exist?
            if app.CreateGridButton.Enable == "off"
                GridFlag = true;
            else
                GridFlag = false;
            end
        
            switch(value)
                case 'Geometry'
                    app.PlotButton.Enable = "on";
                    app.GeoAxes.YLabel.String = 'Depth (km)';
                    FaultLength = app.FaultLengthkmEditField.Value;
                    app.yminEditField.Value = -FaultLength/2;
                    app.ymaxEditField.Value = FaultLength/2;

                    if GridFlag
                        EnableControls(app);
                    end

            %%% The 'Shear Stress', 'Normal Stress', and 'Pore Pressure' cases all share common code
            %%% so could be consolidatid.
                case 'Shear Stress'
                    if GridFlag
                        app.PlotButton.Enable = "on";
                        EnableControls(app);
                        app.GeoAxes.YLabel.String = 'Shear Stress (MPa)';

                    %%% Get the initial shear stress to set the y-axis limits.
                        N = app.p.Geometry.GridPoints;
                        % Vars_0 = app.p.Geometry.InitConditions;
                        Vars_0 = app.p.InitialConditions.Vars_init;
                        ShearStress = Vars_0(N+1:2*N);
                        app.yminEditField.Value = 0;
                        app.ymaxEditField.Value = 1.5*max(ShearStress);                        
                    else
                        app.PlotButton.Enable = "off";
                    end

                %%% THIS SHOULD ACCOUNT FOR CASES WHERE THE BACKGROUND NORMAL STRESS IS
                %%% VARIABLE, BUT THERE IS NO ELASTIC CHANGE IN NORMAL STRESS.
                case 'Normal Stress'
                %%% For now only enable when the grid exists.
                    if GridFlag
                        switch(app.GeometryDropDown.Value)
                            case {'Thrust Fault', 'Normal Fault'}
                                app.PlotButton.Enable = "on";
                                EnableControls(app);
                                app.GeoAxes.YLabel.String = 'Normal Stress (MPa)';

                            %%% Get the initial shear stress to set the y-axis limits.
                                N = app.p.Geometry.GridPoints;
                                % Vars_0 = app.p.Geometry.InitConditions;
                                Vars_0 = app.p.InitialConditions.Vars_init;
                                NormalStress = Vars_0(3*N+1:4*N);
                                app.yminEditField.Value = 0;
                                app.ymaxEditField.Value = 1.5*max(NormalStress);

                            case {'Full Space', 'Strike Slip'}
                                app.PlotButton.Enable = "off";
                                DisableControls(app);
                        end
                    else
                        app.PlotButton.Enable = "off";
                    end

                case 'Pore Pressure'
                    if GridFlag
                    %%% Only turn on the plot button is a pore pressure vector exists.
                        N = app.p.Geometry.GridPoints;
                        % Vars_0 = app.p.Geometry.InitConditions;
                        Vars_0 = app.p.InitialConditions.Vars_init;
                        if numel(Vars_0) > 4*N
                            app.PlotButton.Enable = "on";
                            EnableControls(app);
                            app.GeoAxes.YLabel.String = 'Pore Pressure (MPa)';                            

                        %%% Determine the y-axis limits based on the stress case.
                            StressCase = app.p.Options.EffectiveStress;
                            UES_IC_Flag = false;
                            EffStress0 = UpdateEffectiveStress(app, UES_IC_Flag);
                            PorePressure0 = Vars_0(4*N+1:5*N);
                            switch StressCase
                                case 'Constant'
                                    app.yminEditField.Value = -1;
                                    app.ymaxEditField.Value = EffStress0;
                                case 'Pore Pressure Ratio'
                                    app.yminEditField.Value = 0;
                                    app.ymaxEditField.Value = 1.5*max(PorePressure0);
                            end
                        else
                            app.PlotButton.Enable = "off";
                            DisableControls(app);
                        end
                    else
                        app.PlotButton.Enable = "off";
                    end
            end
            drawnow
        end

        % Value changed function: SourceFunctionCheckBox
        function SourceFunctionCheckBoxValueChanged(app, event)
            value = app.SourceFunctionCheckBox.Value;
            if value == 1
                app.SourceFunctionName.Enable = 'on';
            else
                app.SourceFunctionName.Enable = 'off';
            end

        %%% Enable or disable the InitialPorePressureEditField as appropriate.
            InitialPorePressureField(app);
        
        %%% For now, disable the simulation controls and require a new grid.
            app.CreateGridButton.Enable = "on";
            DisableControls(app);
        end

        % Value changed function: DiffusionCheckBox
        function DiffusionCheckBoxValueChanged(app, event)
            value = app.DiffusionCheckBox.Value;
            if value == 1
                app.PorosityEditField.Enable = 'on';
                app.FluidViscosityMPasEditField.Enable = 'on';
                app.Compressibility1MPaEditField.Enable = 'on';
                app.Permeabilitym2EditField.Enable = 'on';
                app.DiffusivityLabel.Enable = "on";
            else
                app.PorosityEditField.Enable = 'off';
                app.FluidViscosityMPasEditField.Enable = 'off';
                app.Compressibility1MPaEditField.Enable = 'off';
                app.Permeabilitym2EditField.Enable = 'off';
                app.DiffusivityLabel.Enable = "off";
            end

        %%% Enable or disable the InitialPorePressureEditField as appropriate.
            InitialPorePressureField(app);

        %%% For now, disable the simulation controls and require a new grid.
            app.CreateGridButton.Enable = "on";
            DisableControls(app);
        end

        % Value changed function: Permeabilitym2EditField
        function Permeabilitym2EditFieldValueChanged(app, event)
            %value = app.Permeabilitym2EditField.Value;
            UpdateDiffusivity(app);
        end

        % Value changed function: Compressibility1MPaEditField
        function Compressibility1MPaEditFieldValueChanged(app, event)
            % value = app.Compressibility1MPaEditField.Value;
            UpdateDiffusivity(app);
        end

        % Value changed function: FluidViscosityMPasEditField
        function FluidViscosityMPasEditFieldValueChanged(app, event)
            % value = app.FluidViscosityMPasEditField.Value;
            UpdateDiffusivity(app);
        end

        % Value changed function: PorosityEditField
        function PorosityEditFieldValueChanged(app, event)
            % value = app.PorosityEditField.Value;
            UpdateDiffusivity(app);
        end

        % Value changed function: datCheckBox
        function datCheckBoxValueChanged(app, event)
        %%% CHANGE GetParameters(app) TO READ THE VALUE OF THE CHECKBOX AND THEN STORE IT
        %%% IN p.Options.
            value = app.datCheckBox.Value;
            if value == 1
                app.ResumeButton.Enable = "off";
                app.PlotControlsPanel.Enable = "off";
            %%% Un-check the PlotsCheckBox and disable it.
                app.PlotsOnCheckBox.Value = 0;
                app.PlotsOnCheckBox.Enable = "off";
            else
            %%% Only enable the ResumeButton if the simulation controls are enabled.
                if app.StartButton.Enable == "on"
                    app.ResumeButton.Enable = "on";
                end
                app.PlotControlsPanel.Enable = "on";
                app.PlotsOnCheckBox.Enable = "on";
            end
        end

        % Value changed function: SolidDensityEditField
        function SolidDensityEditFieldValueChanged(app, event)
            % value = app.SolidDensityEditField.Value;
            app.CreateGridButton.Enable = "on";
            DisableControls(app);
            UpdateGridInfo(app, 'Lbdxi');
        end

        % Value changed function: FluidDensityEditField
        function FluidDensityEditFieldValueChanged(app, event)
            % value = app.FluidDensityEditField.Value;
            app.CreateGridButton.Enable = "on";
            DisableControls(app);
            UpdateGridInfo(app, 'Lbdxi');
        end

        % Value changed function: InitialPorePressureEditField
        function InitialPorePressureEditFieldValueChanged(app, event)
        %%% This field should only be active when ConstantEffectiveStress and SourceFunction
        %%% or Diffusion are selected.
            %value = app.InitialPorePressureEditField.Value;
            % InitialConditions(app);
            % app.CreateGridButton.Enable = "on";
            % DisableControls(app);
        end

        % Button pushed function: PlotButton_Lunar
        function PlotButton_LunarPushed(app, event)
        %%% Plot the location of the fault on the Moon map. The fault is represented
        %%% by and arrow that points along dip, with an orientation determined by the
        %%% fault azimuth. The length of the arrow is chosen so that it is visible on
        %%% the plot, and does not correspond to the length of the fault.

        %%% Get the latitude, longitude, and azimuth of the fault.
            lat_updip = app.LatitudeEditField.Value;
            lon_updip = app.LongitudeEditField.Value;
            azimuth = app.AzimuthEditField.Value;

        %%% Compute the coordinates of the of the downdip end of the arrow.
            [lat_downdip, lon_downdip] = LunarFaultPlotTest1(lat_updip, lon_updip,...
                azimuth);
            
        %%% Delete any previously plotted fault location.
            h = findobj(app.MoonAxes, 'Type', 'Arrow');
            delete(h);

        %%% Plot the current fault location.
            % hold(app.MoonAxes, 'on')
            % plot(app.MoonAxes, lon, lat, 'r|', LineWidth = 2, MarkerSize = 10)
            % hold(app.MoonAxes, 'off')

            fault_arrow = annotation(app.MoonAxes.Parent, 'arrow');
            fault_arrow.Parent = app.MoonAxes;
            fault_arrow.Position = [lon_updip, lat_updip, lon_downdip-lon_updip,...
                lat_downdip-lat_updip];
            fault_arrow.Color = 'g';
            fault_arrow.LineWidth = 2;
            fault_arrow.HeadLength = 7;
            fault_arrow.HeadWidth = 7;


            function [lat_downdip, lon_downdip] = LunarFaultPlotTest1(lat_updip, lon_updip,...
                    azimuth)
                
            %%% Input
            %%% 1. Longitude of the updip edge of the fault.
            %%% 2. Latitude of the updip edge of the fault.
            %%% 3. Azimuth of the fault.
            
            %%% Output
            %%% 1. Longitude of the downdip fault arrow.
            %%% 2. Latitude of the updip fault arrow.
            
            %%% Obtain the downdip coordinates by moving along the surface of the moon, in the
            %%% direction of the azimuth.
            
            %%% Function reckon takes all argumets in degrees. Spherical arclengths do not map
            %%% linearly to cartesian distances in the mercator projection. So iterate until the
            %%% cartesian length of the fault arrow obtains a set value.
                fault_cartlength_target = 15;               % [deg?] cartesian length of fault arrow  
                fault_arclen = 20;                          % [deg] arclength of the fault arrow
                
                %%% Bisection method to get the desired fault arrow length.
                %%% Maximum number of steps.
                q_max = 100;
                q = 1;
                tol = 0.5;
                a = 0;
                b = fault_arclen;
                
                while q < q_max
                
                    fault_arclen = (b + a)/2;
                    [lat_downdip, lon_downdip] = reckon(lat_updip, lon_updip, fault_arclen, azimuth);
                %%% Cartesian length of the fault arrow.
                    fault_cartlength = pdist([lon_downdip, lat_downdip; lon_updip, lat_updip]);
                
                    if abs(fault_cartlength_target - fault_cartlength) < tol
                        break
                    end
                
                    if fault_cartlength > fault_cartlength_target
                        b = fault_arclen;
                    else
                        a = fault_arclen;
                    end                
                    q = q + 1;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RSFaultZUIFigure = uifigure('Visible', 'off');
            app.RSFaultZUIFigure.Name = 'RSFaultZ';
            app.VelAxes = uiaxes(app.RSFaultZUIFigure);
            xlabel(app.VelAxes, 'Distance (km)')
            ylabel(app.VelAxes, 'Slip Speed (m / s)')
            app.VelAxes.XTickLabelRotationMode = 'manual';
            app.VelAxes.YTickLabelRotationMode = 'manual';
            app.VelAxes.ZTickLabelRotationMode = 'manual';
            app.GeometryPanel = uipanel(app.RSFaultZUIFigure);
            app.GeometryPanel.Title = 'Geometry';
            app.SurfaceSlopeEditFieldLabel = uilabel(app.GeometryPanel);
            app.SurfaceSlopeEditFieldLabel.Text = 'Surface Slope';
            app.SurfaceSlopeEditField = uieditfield(app.GeometryPanel, 'numeric');
            app.SurfaceSlopeEditField.ValueChangedFcn = createCallbackFcn(app, @SurfaceSlopeEditFieldValueChanged, true);
            app.DipAngleEditFieldLabel = uilabel(app.GeometryPanel);
            app.DipAngleEditFieldLabel.Text = 'Dip Angle';
            app.DipAngleEditField = uieditfield(app.GeometryPanel, 'numeric');
            app.DipAngleEditField.ValueChangedFcn = createCallbackFcn(app, @DipAngleEditFieldValueChanged, true);
            app.FaultLengthkmEditFieldLabel = uilabel(app.GeometryPanel);
            app.FaultLengthkmEditFieldLabel.Text = 'Fault Length [km]';
            app.FaultLengthkmEditField = uieditfield(app.GeometryPanel, 'numeric');
            app.FaultLengthkmEditField.ValueChangedFcn = createCallbackFcn(app, @FaultLengthkmEditFieldValueChanged, true);
            app.BurialDepthkmEditFieldLabel = uilabel(app.GeometryPanel);
            app.BurialDepthkmEditFieldLabel.Text = 'Burial Depth [km]';
            app.BurialDepthkmEditField = uieditfield(app.GeometryPanel, 'numeric');
            app.BurialDepthkmEditField.ValueChangedFcn = createCallbackFcn(app, @BurialDepthkmEditFieldValueChanged, true);
            app.GeometryDropDownLabel = uilabel(app.GeometryPanel);
            app.GeometryDropDownLabel.Text = 'Geometry';
            app.GeometryDropDown = uidropdown(app.GeometryPanel);
            app.GeometryDropDown.Items = {'Full Space', 'Normal Fault', 'Thrust Fault', 'Strike Slip', 'Landslide', 'Lunar Thrust'};
            app.GeometryDropDown.ValueChangedFcn = createCallbackFcn(app, @GeometryDropDownValueChanged, true);
            app.GeometryDropDown.Value = 'Thrust Fault';
            app.FrictionParametersPanel = uipanel(app.RSFaultZUIFigure);
            app.FrictionParametersPanel.Title = 'Friction Parameters';
            app.aEditFieldLabel = uilabel(app.FrictionParametersPanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.FrictionParametersPanel, 'numeric');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @aEditFieldValueChanged, true);
            app.bEditFieldLabel = uilabel(app.FrictionParametersPanel);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.FrictionParametersPanel, 'numeric');
            app.bEditField.ValueChangedFcn = createCallbackFcn(app, @bEditFieldValueChanged, true);
            app.dcmLabel = uilabel(app.FrictionParametersPanel);
            app.dcmLabel.Text = 'dc [m]';
            app.dcmEditField = uieditfield(app.FrictionParametersPanel, 'numeric');
            app.dcmEditField.ValueChangedFcn = createCallbackFcn(app, @dcmEditFieldValueChanged, true);
            app.v0msLabel = uilabel(app.FrictionParametersPanel);
            app.v0msLabel.Text = 'v0 [m/s]';
            app.v0EditField = uieditfield(app.FrictionParametersPanel, 'numeric');
            app.mu0EditFieldLabel = uilabel(app.FrictionParametersPanel);
            app.mu0EditFieldLabel.Text = 'mu0';
            app.mu0EditField = uieditfield(app.FrictionParametersPanel, 'numeric');
            app.SimulationControlsPanel = uipanel(app.RSFaultZUIFigure);
            app.SimulationControlsPanel.Title = 'Simulation Controls';
            app.StartButton = uibutton(app.SimulationControlsPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.SimulationControlsPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ExportButton = uibutton(app.SimulationControlsPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.StructureNameLabel = uilabel(app.SimulationControlsPanel);
            app.StructureNameLabel.Text = {'Structure '; 'Name'};
            app.StructureNameEditField = uieditfield(app.SimulationControlsPanel, 'text');
            app.ResumeButton = uibutton(app.SimulationControlsPanel, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.StateLawDropDownLabel = uilabel(app.SimulationControlsPanel);
            app.StateLawDropDownLabel.Text = 'State Law';
            app.StateLawDropDown = uidropdown(app.SimulationControlsPanel);
            app.StateLawDropDown.Items = {'Aging', 'Slip', 'Other'};
            app.StateLawDropDown.Value = 'Slip';
            app.PlotsOnCheckBox = uicheckbox(app.SimulationControlsPanel);
            app.PlotsOnCheckBox.Text = 'Plots On';
            app.PlotsOnCheckBox.Value = true;
            app.RunTimeyrEditFieldLabel = uilabel(app.SimulationControlsPanel);
            app.RunTimeyrEditFieldLabel.Text = 'Run Time [yr]';
            app.RunTimeyrEditField = uieditfield(app.SimulationControlsPanel, 'numeric');
            app.datCheckBox = uicheckbox(app.SimulationControlsPanel);
            app.datCheckBox.ValueChangedFcn = createCallbackFcn(app, @datCheckBoxValueChanged, true);
            app.datCheckBox.Text = '.dat';
            app.TimeLabel = uilabel(app.RSFaultZUIFigure);
            app.TimeLabel.Text = 'Time:               ';
            app.ExecutionTimesLabel = uilabel(app.RSFaultZUIFigure);
            app.ExecutionTimesLabel.Text = {'Execution'; 'Time [s]'};
            app.Label = uilabel(app.RSFaultZUIFigure);
            app.Label.Text = ':';
            app.TabGroup = uitabgroup(app.RSFaultZUIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.EditField_5Label = uilabel(app.MainTab);
            app.EditField_5Label.Text = '';
            app.EffectiveNormalStressButtonGroup = uibuttongroup(app.MainTab);
            app.EffectiveNormalStressButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EffectiveNormalStressButtonGroupSelectionChanged, true);
            app.EffectiveNormalStressButtonGroup.Title = 'Effective Normal Stress';
            app.ConstantMPaButton = uiradiobutton(app.EffectiveNormalStressButtonGroup);
            app.ConstantMPaButton.Text = 'Constant [MPa]';
            app.ConstantMPaButton.Value = true;
            app.PorePressureRatioButton = uiradiobutton(app.EffectiveNormalStressButtonGroup);
            app.PorePressureRatioButton.Text = {'Pore Pressure'; 'Ratio'};
            app.NormalStressMPaEditField = uieditfield(app.EffectiveNormalStressButtonGroup, 'numeric');
            app.NormalStressMPaEditField.ValueChangedFcn = createCallbackFcn(app, @NormalStressMPaEditFieldValueChanged, true);
            app.PorePressureRatioEditField = uieditfield(app.EffectiveNormalStressButtonGroup, 'numeric');
            app.PorePressureRatioEditField.ValueChangedFcn = createCallbackFcn(app, @PorePressureRatioEditFieldValueChanged, true);
            app.LithostaticStressButton = uiradiobutton(app.EffectiveNormalStressButtonGroup);
            app.LithostaticStressButton.Text = 'Lithostatic Stress';
            app.SolidDensitykgm3EditFieldLabel = uilabel(app.EffectiveNormalStressButtonGroup);
            app.SolidDensitykgm3EditFieldLabel.Text = 'Solid Density [kg/m3]';
            app.SolidDensityEditField = uieditfield(app.EffectiveNormalStressButtonGroup, 'numeric');
            app.SolidDensityEditField.ValueChangedFcn = createCallbackFcn(app, @SolidDensityEditFieldValueChanged, true);
            app.FluidDensitykgm3EditFieldLabel = uilabel(app.EffectiveNormalStressButtonGroup);
            app.FluidDensitykgm3EditFieldLabel.Text = 'Fluid Density [kg/m3]';
            app.FluidDensityEditField = uieditfield(app.EffectiveNormalStressButtonGroup, 'numeric');
            app.FluidDensityEditField.ValueChangedFcn = createCallbackFcn(app, @FluidDensityEditFieldValueChanged, true);
            app.EffectiveStressLabel = uilabel(app.EffectiveNormalStressButtonGroup);
            app.EffectiveStressLabel.Text = '               [MPa]';
            app.LambdaLabel = uilabel(app.EffectiveNormalStressButtonGroup);
            app.LambdaLabel.Text = '(0 < l < 1)';
            app.ElasticParametersPanel = uipanel(app.MainTab);
            app.ElasticParametersPanel.Title = 'Elastic Parameters';
            app.ShearModulusGPaEditFieldLabel = uilabel(app.ElasticParametersPanel);
            app.ShearModulusGPaEditFieldLabel.Text = 'Shear Modulus [GPa]';
            app.ShearModulusGPaEditField = uieditfield(app.ElasticParametersPanel, 'numeric');
            app.ShearModulusGPaEditField.ValueChangedFcn = createCallbackFcn(app, @ShearModulusGPaEditFieldValueChanged, true);
            app.PoissonRatioEditFieldLabel = uilabel(app.ElasticParametersPanel);
            app.PoissonRatioEditFieldLabel.Text = 'Poisson Ratio';
            app.PoissonRatioEditField = uieditfield(app.ElasticParametersPanel, 'numeric');
            app.PoissonRatioEditField.ValueChangedFcn = createCallbackFcn(app, @PoissonRatioEditFieldValueChanged, true);
            app.SWaveSpeedmsEditFieldLabel = uilabel(app.ElasticParametersPanel);
            app.SWaveSpeedmsEditFieldLabel.Text = 'S-Wave Speed [m/s]';
            app.vsmsEditField = uieditfield(app.ElasticParametersPanel, 'numeric');
            app.FluidFlowTab = uitab(app.TabGroup);
            app.FluidFlowTab.Title = 'Fluid Flow';
            app.DiffusionCheckBox = uicheckbox(app.FluidFlowTab);
            app.DiffusionCheckBox.ValueChangedFcn = createCallbackFcn(app, @DiffusionCheckBoxValueChanged, true);
            app.DiffusionCheckBox.Text = 'Diffusion';
            app.Permeabilitym2Label = uilabel(app.FluidFlowTab);
            app.Permeabilitym2Label.Text = {'Permeability '; '[m2]'};
            app.Permeabilitym2EditField = uieditfield(app.FluidFlowTab, 'numeric');
            app.Permeabilitym2EditField.ValueChangedFcn = createCallbackFcn(app, @Permeabilitym2EditFieldValueChanged, true);
            app.Compressibility1MPaLabel = uilabel(app.FluidFlowTab);
            app.Compressibility1MPaLabel.Text = {'Compressibility'; '[1/MPa]'};
            app.Compressibility1MPaEditField = uieditfield(app.FluidFlowTab, 'numeric');
            app.Compressibility1MPaEditField.ValueChangedFcn = createCallbackFcn(app, @Compressibility1MPaEditFieldValueChanged, true);
            app.FluidViscosityMpasLabel = uilabel(app.FluidFlowTab);
            app.FluidViscosityMpasLabel.Text = {'Fluid Viscosity'; '[MPa s]'};
            app.FluidViscosityMPasEditField = uieditfield(app.FluidFlowTab, 'numeric');
            app.FluidViscosityMPasEditField.ValueChangedFcn = createCallbackFcn(app, @FluidViscosityMPasEditFieldValueChanged, true);
            app.PorosityEditFieldLabel = uilabel(app.FluidFlowTab);
            app.PorosityEditFieldLabel.Text = 'Porosity';
            app.PorosityEditField = uieditfield(app.FluidFlowTab, 'numeric');
            app.PorosityEditField.ValueChangedFcn = createCallbackFcn(app, @PorosityEditFieldValueChanged, true);
            app.SourceFunctionName = uieditfield(app.FluidFlowTab, 'text');
            app.SourceFunctionCheckBox = uicheckbox(app.FluidFlowTab);
            app.SourceFunctionCheckBox.ValueChangedFcn = createCallbackFcn(app, @SourceFunctionCheckBoxValueChanged, true);
            app.SourceFunctionCheckBox.Text = 'Source Function';
            app.DiffusivityLabel = uilabel(app.FluidFlowTab);
            app.DiffusivityLabel.Text = 'Diffusivity =                 [m2/s]';
            app.InitialExcessPorePressureMPaEditFieldLabel = uilabel(app.FluidFlowTab);
            app.InitialExcessPorePressureMPaEditFieldLabel.Text = {'Initial Excess'; 'Pore Pressure [MPa]'};
            app.InitialPorePressureEditField = uieditfield(app.FluidFlowTab, 'numeric');
            app.InitialPorePressureEditField.ValueChangedFcn = createCallbackFcn(app, @InitialPorePressureEditFieldValueChanged, true);
            app.TabGroup2 = uitabgroup(app.RSFaultZUIFigure);
            app.GridControlsTab = uitab(app.TabGroup2);
            app.GridControlsTab.Title = 'Grid Controls';
            app.CreateGridButton = uibutton(app.GridControlsTab, 'push');
            app.CreateGridButton.ButtonPushedFcn = createCallbackFcn(app, @CreateGridButtonPushed, true);
            app.CreateGridButton.Text = 'Create Grid';
            app.hkmLabel = uilabel(app.GridControlsTab);
            app.hkmLabel.Text = 'h* =               [km]';
            app.LbkmLabel = uilabel(app.GridControlsTab);
            app.LbkmLabel.Text = 'Lb =               [km]';
            app.ImportButton = uibutton(app.GridControlsTab, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ImportEditField = uieditfield(app.GridControlsTab, 'text');
            app.FourierSpaceCheckBox = uicheckbox(app.GridControlsTab);
            app.FourierSpaceCheckBox.ValueChangedFcn = createCallbackFcn(app, @FourierSpaceCheckBoxValueChanged, true);
            app.FourierSpaceCheckBox.Text = 'Fourier Space';
            app.GridPointsEditFieldLabel = uilabel(app.GridControlsTab);
            app.GridPointsEditFieldLabel.Text = 'Grid Points';
            app.GridPointsEditField = uieditfield(app.GridControlsTab, 'numeric');
            app.GridPointsEditField.ValueDisplayFormat = '%.0f';
            app.GridPointsEditField.ValueChangedFcn = createCallbackFcn(app, @GridPointsEditFieldValueChanged, true);
            app.dximEditFieldLabel = uilabel(app.GridControlsTab);
            app.dximEditFieldLabel.Text = 'dxi [m]';
            app.dximEditField = uieditfield(app.GridControlsTab, 'numeric');
            app.dximEditField.ValueChangedFcn = createCallbackFcn(app, @dximEditFieldValueChanged, true);
            app.LbdxiEditFieldLabel = uilabel(app.GridControlsTab);
            app.LbdxiEditFieldLabel.Text = 'Lb / dxi';
            app.LbdxiEditField = uieditfield(app.GridControlsTab, 'numeric');
            app.LbdxiEditField.ValueChangedFcn = createCallbackFcn(app, @LbdxiEditFieldValueChanged, true);
            app.InitialConditionsTab = uitab(app.TabGroup2);
            app.InitialConditionsTab.Title = 'Initial Conditions';
            app.RandomVariationEditFieldLabel = uilabel(app.InitialConditionsTab);
            app.RandomVariationEditFieldLabel.Text = {'Random'; 'Variation [%]'};
            app.RandomVariationEditField = uieditfield(app.InitialConditionsTab, 'numeric');
            app.InitialVelocitymsEditFieldLabel = uilabel(app.InitialConditionsTab);
            app.InitialVelocitymsEditFieldLabel.Text = {'Initial'; 'Velocity [m/s]'};
            app.InitialVelocitymsEditField = uieditfield(app.InitialConditionsTab, 'numeric');
            app.LoadingRatemsEditFieldLabel = uilabel(app.InitialConditionsTab);
            app.LoadingRatemsEditFieldLabel.Text = {'Loading'; 'Rate [m/s]'};
            app.LoadingRatemsEditField = uieditfield(app.InitialConditionsTab, 'numeric');
            app.SteadyStateConditionButtonGroup = uibuttongroup(app.InitialConditionsTab);
            app.SteadyStateConditionButtonGroup.Title = 'Steady State Condition';
            app.StateVariableButton = uiradiobutton(app.SteadyStateConditionButtonGroup);
            app.StateVariableButton.Text = 'State Variable';
            app.StateVariableButton.Value = true;
            app.ShearStressButton = uiradiobutton(app.SteadyStateConditionButtonGroup);
            app.ShearStressButton.Text = 'Shear Stress';
            app.PlotTab1 = uitabgroup(app.RSFaultZUIFigure);
            app.MainTab_2 = uitab(app.PlotTab1);
            app.MainTab_2.Title = 'Main';
            app.GeoAxes = uiaxes(app.MainTab_2);
            xlabel(app.GeoAxes, 'Distance (km)')
            ylabel(app.GeoAxes, 'Depth (km)')
            app.GeoAxes.XTickLabelRotationMode = 'manual';
            app.GeoAxes.YTickLabelRotationMode = 'manual';
            app.GeoAxes.ZTickLabelRotationMode = 'manual';
            app.PlotControlsPanel = uipanel(app.MainTab_2);
            app.PlotControlsPanel.Title = 'Plot Controls';
            app.PlotTypeDropDownLabel = uilabel(app.PlotControlsPanel);
            app.PlotTypeDropDownLabel.Text = 'Plot Type';
            app.PlotTypeDropDown = uidropdown(app.PlotControlsPanel);
            app.PlotTypeDropDown.Items = {'Geometry', 'Shear Stress', 'Normal Stress', 'Pore Pressure'};
            app.PlotTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotTypeDropDownValueChanged, true);
            app.PlotTypeDropDown.Value = 'Geometry';
            app.PlotButton = uibutton(app.PlotControlsPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.yminLabel = uilabel(app.PlotControlsPanel);
            app.yminLabel.Text = 'y min';
            app.yminEditField = uieditfield(app.PlotControlsPanel, 'numeric');
            app.ymaxEditFieldLabel = uilabel(app.PlotControlsPanel);
            app.ymaxEditFieldLabel.Text = 'y max';
            app.ymaxEditField = uieditfield(app.PlotControlsPanel, 'numeric');
            app.TheMoonTab = uitab(app.PlotTab1);
            app.TheMoonTab.Title = 'The Moon';
            app.TheMoonPanel = uipanel(app.TheMoonTab);
            app.MoonAxes = uiaxes(app.TheMoonPanel);
            xlabel(app.MoonAxes, 'Longitude')
            ylabel(app.MoonAxes, 'Latitude')
            zlabel(app.MoonAxes, 'Z')
            app.LongitudeEditFieldLabel = uilabel(app.TheMoonPanel);
            app.LongitudeEditFieldLabel.Text = 'Longitude';
            app.LongitudeEditField = uieditfield(app.TheMoonPanel, 'numeric');
            app.LongitudeEditField.Limits = [-180 180];
            app.LatitudeEditFieldLabel = uilabel(app.TheMoonPanel);
            app.LatitudeEditFieldLabel.Text = 'Latitude';
            app.LatitudeEditField = uieditfield(app.TheMoonPanel, 'numeric');
            app.LatitudeEditField.Limits = [-90 90];
            app.AzimuthEditFieldLabel = uilabel(app.TheMoonPanel);
            app.AzimuthEditFieldLabel.Text = 'Azimuth';
            app.AzimuthEditField = uieditfield(app.TheMoonPanel, 'numeric');
            app.AzimuthEditField.Limits = [0 360];
            app.PlotButton_Lunar = uibutton(app.TheMoonPanel, 'push');
            app.PlotButton_Lunar.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_LunarPushed, true);
            app.PlotButton_Lunar.Text = 'Plot';
            % Show the figure after all components are created
            app.RSFaultZUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RSFaultZ

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RSFaultZUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RSFaultZUIFigure)
        end
    end
end